classdef IMU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        StopButton            matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        AccTab                matlab.ui.container.Tab
        AccPanel              matlab.ui.container.Panel
        Acc3EditField         matlab.ui.control.NumericEditField
        Acc3EditFieldLabel    matlab.ui.control.Label
        Acc2EditField         matlab.ui.control.NumericEditField
        Acc2EditFieldLabel    matlab.ui.control.Label
        Acc1EditField         matlab.ui.control.NumericEditField
        Acc1EditFieldLabel    matlab.ui.control.Label
        GyroTab               matlab.ui.container.Tab
        GyroPanel             matlab.ui.container.Panel
        Gyro3EditField        matlab.ui.control.NumericEditField
        Gyro3EditFieldLabel   matlab.ui.control.Label
        Gyro2EditField        matlab.ui.control.NumericEditField
        Gyro2EditFieldLabel   matlab.ui.control.Label
        Gyro1EditField        matlab.ui.control.NumericEditField
        Gyro1EditFieldLabel   matlab.ui.control.Label
        AngleTab              matlab.ui.container.Tab
        AnglePanel            matlab.ui.container.Panel
        Angle3EditField       matlab.ui.control.NumericEditField
        Angle3EditFieldLabel  matlab.ui.control.Label
        Angle2EditField       matlab.ui.control.NumericEditField
        Angle2EditFieldLabel  matlab.ui.control.Label
        Angle1EditField       matlab.ui.control.NumericEditField
        Angle1EditFieldLabel  matlab.ui.control.Label
        MagTab                matlab.ui.container.Tab
        MagPanel              matlab.ui.container.Panel
        Mag3EditField         matlab.ui.control.NumericEditField
        Mag3EditFieldLabel    matlab.ui.control.Label
        Mag2EditField         matlab.ui.control.NumericEditField
        Mag2EditFieldLabel    matlab.ui.control.Label
        Mag1EditField         matlab.ui.control.NumericEditField
        Mag1EditFieldLabel    matlab.ui.control.Label
        LogTextArea           matlab.ui.control.TextArea
        LogTextAreaLabel      matlab.ui.control.Label
        OpenButton            matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isopen = 0; 
        port ='COM8';
        com = [];
        %%
        acc;
        gyro;
        mag;
        angle;
        dist;
        %%
        logbuffer;
        log_raw;
        log_length = 2;
        Buff = dsp.AsyncBuffer(100);
        plot_timer;
        rec_timer;
% %             app.acc(i,:) = Buff(1:3);
% %             app.gyro(i,:) = Buff(4:6);
% %             app.angle(i,:) = Buff(7:9);
% %             app.mag(i,:) = Buff(10:12);
% %             app.dist(i,:) = Buff(13);
    end
    
    methods (Access = private)
        function logger_init(app,n)
            app.log_length = n;
            app.log_raw = 1;
            app.logbuffer = repmat(" ",1,app.log_length);
        end
        function logger(app,str)
            str = string(str);
            app.logbuffer(app.log_raw) = str;
            assignin('base','logbuf',app.logbuffer);
%             text = [];
%             cnt = 1;
%             for i = 1:app.log_length
%                 if app.logbuffer(i) ~= " "
%                     text = [text,app.logbuffer(i)];
%                     %text{i} =app.logbuffer(i);
%                 end
%             end
            text = sprintf("%s \n%s",app.logbuffer(1),app.logbuffer(2));
            assignin('base','text',text);
            app.LogTextArea.Value =  text;
            
        end
        function openSeriesPort(app)
            if ~isempty(app.com)
                app.logger("already open");
                return
            end
            app.logger("Opening");
            bound = 9600;
            app.com = serialport('COM8',bound);
            app.logger("Testing");
            for i = 1:10
                readline(app.com);
            end
            app.logger("Open Sucessfully");
            app.isopen = 1;
        end
        function getSeriesData(app)
            app.log_raw = 1;
            tic;
            
            str = readline(app.com);
            data = sscanf(str,'acc: %f %f %f gyro: %f %f %f angle: %f %f %f mag: %f %f %f dist: %f', 13);
            if length(data)~=13
                d = toc;
                app.logger('Serial Error');
                return 
            end
            data = data';
            app.Acc1EditField.Value = data(1);
            app.Acc2EditField.Value = data(2);
            app.Acc3EditField.Value = data(3);
            app.Gyro1EditField.Value = data(4);
            app.Gyro2EditField.Value = data(5);
            app.Gyro3EditField.Value = data(6);
            app.Angle1EditField.Value = data(7);
            app.Angle2EditField.Value = data(8);
            app.Angle3EditField.Value = data(9);
            app.Mag1EditField.Value = data(10);
            app.Mag2EditField.Value = data(11);
            app.Mag3EditField.Value = data(12);

            write(app.Buff,data);
            if app.com.NumBytesAvailable >1000
                flush(app.com)
%                 pause(0.1);
            end
            
            app.logger(['Recive in ',num2str(toc),'s']);
%             app.logger(num2str(app.com.NumBytesAvailable));
        end
        function plotAxes(app)
%             app.logger("Ploting");
            app.log_raw = 2;
            assignin('base','appbuf',app.Buff);
%             if app.Buff.NumUnreadSamples <= 100
%                 app.logger("Less than 100 element in buffer");
%                 return
%             end

            data = peek(app.Buff,app.Buff.NumUnreadSamples);
%             data = peek(app.Buff,100);
            if isempty(data)
                app.logger("Read Error");
                return
            end
            assignin('base','appdata',data);
            tic
            app.acc = data(:,1:3);
            app.gyro = data(: , 4:6);
            app.angle= data(: , 7:9);
            app.mag = data(: , 10:12);
            app.dist = data(: , 13);
%             quant = eul2quat(data(:,7:9));
%             plot(app.UIAxes,quant(:,2:4)./quant(:,1));
            plot(app.UIAxes,app.gyro);
%             legend(app.UIAxes,["x","y","z"]);
            plot(app.UIAxes2,app.angle);
%             legend(app.UIAxes2,["x","y","z"]);
            plot(app.UIAxes3,app.acc);
%             legend(app.UIAxes3,["x","y","z"]);
            plot(app.UIAxes4,app.dist);
            drawnow;
            app.logger(['Plot in ',num2str(toc),'s']);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.logger_init(2);
            app.openSeriesPort();
            
            if app.isopen == 0
                return
            end
            app.plot_timer = timer('TimerFcn',@(~,~)app.plotAxes(),'Period',0.01,'BusyMode','queue','ExecutionMode','fixedSpacing');
            app.rec_timer = timer('TimerFcn',@(~,~)app.getSeriesData(),'Period',0.01,'BusyMode','queue','ExecutionMode','fixedSpacing');
            % 'fixedSpacing' 上次执行完0.05s后继续执行回调
            start(app.plot_timer);
            start(app.rec_timer);
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            valueList= ['COM2';'COM6';'COM8'];
            app.port = valueList(value,:);
            app.logger(app.port);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if isempty(app.port) || isempty(app.plot_timer) || isempty(app.rec_timer)
                return
            end
            stop(app.plot_timer);
            stop(app.rec_timer);
            delete(app.com);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Angle')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Acc')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Distance')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'COM2', 'COM6', 'COM8'};
            app.DropDown.ItemsData = [1 2 3];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 1;
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AccTab = uitab(app.TabGroup);
            app.AccTab.Title = 'Acc';
            app.AccPanel = uipanel(app.AccTab);
            app.AccPanel.Title = 'Acc';
            app.Acc1EditFieldLabel = uilabel(app.AccPanel);
            app.Acc1EditFieldLabel.Text = 'Acc1';
            app.Acc1EditField = uieditfield(app.AccPanel, 'numeric');
            app.Acc2EditFieldLabel = uilabel(app.AccPanel);
            app.Acc2EditFieldLabel.Text = 'Acc2';
            app.Acc2EditField = uieditfield(app.AccPanel, 'numeric');
            app.Acc3EditFieldLabel = uilabel(app.AccPanel);
            app.Acc3EditFieldLabel.Text = 'Acc3';
            app.Acc3EditField = uieditfield(app.AccPanel, 'numeric');
            app.GyroTab = uitab(app.TabGroup);
            app.GyroTab.Title = 'Gyro';
            app.GyroPanel = uipanel(app.GyroTab);
            app.GyroPanel.Title = 'Gyro';
            app.Gyro1EditFieldLabel = uilabel(app.GyroPanel);
            app.Gyro1EditFieldLabel.Text = 'Gyro1';
            app.Gyro1EditField = uieditfield(app.GyroPanel, 'numeric');
            app.Gyro2EditFieldLabel = uilabel(app.GyroPanel);
            app.Gyro2EditFieldLabel.Text = 'Gyro2';
            app.Gyro2EditField = uieditfield(app.GyroPanel, 'numeric');
            app.Gyro3EditFieldLabel = uilabel(app.GyroPanel);
            app.Gyro3EditFieldLabel.Text = 'Gyro3';
            app.Gyro3EditField = uieditfield(app.GyroPanel, 'numeric');
            app.AngleTab = uitab(app.TabGroup);
            app.AngleTab.Title = 'Angle';
            app.AnglePanel = uipanel(app.AngleTab);
            app.AnglePanel.Title = 'Angle';
            app.Angle1EditFieldLabel = uilabel(app.AnglePanel);
            app.Angle1EditFieldLabel.Text = 'Angle1';
            app.Angle1EditField = uieditfield(app.AnglePanel, 'numeric');
            app.Angle2EditFieldLabel = uilabel(app.AnglePanel);
            app.Angle2EditFieldLabel.Text = 'Angle2';
            app.Angle2EditField = uieditfield(app.AnglePanel, 'numeric');
            app.Angle3EditFieldLabel = uilabel(app.AnglePanel);
            app.Angle3EditFieldLabel.Text = 'Angle3';
            app.Angle3EditField = uieditfield(app.AnglePanel, 'numeric');
            app.MagTab = uitab(app.TabGroup);
            app.MagTab.Title = 'Mag';
            app.MagPanel = uipanel(app.MagTab);
            app.MagPanel.Title = 'Mag';
            app.Mag1EditFieldLabel = uilabel(app.MagPanel);
            app.Mag1EditFieldLabel.Text = 'Mag1';
            app.Mag1EditField = uieditfield(app.MagPanel, 'numeric');
            app.Mag2EditFieldLabel = uilabel(app.MagPanel);
            app.Mag2EditFieldLabel.Text = 'Mag2';
            app.Mag2EditField = uieditfield(app.MagPanel, 'numeric');
            app.Mag3EditFieldLabel = uilabel(app.MagPanel);
            app.Mag3EditFieldLabel.Text = 'Mag3';
            app.Mag3EditField = uieditfield(app.MagPanel, 'numeric');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end