classdef Media_Player_Filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        FilterPanel                   matlab.ui.container.Panel
        Slider10                      matlab.ui.control.Slider
        Slider9                       matlab.ui.control.Slider
        Slider8                       matlab.ui.control.Slider
        Slider7                       matlab.ui.control.Slider
        Slider6                       matlab.ui.control.Slider
        Slider5                       matlab.ui.control.Slider
        Slider4                       matlab.ui.control.Slider
        Slider3                       matlab.ui.control.Slider
        Slider2                       matlab.ui.control.Slider
        Slider                        matlab.ui.control.Slider
        Tab2                          matlab.ui.container.Tab
        UIAxes5                       matlab.ui.control.UIAxes
        AudioSourceButtonGroup        matlab.ui.container.ButtonGroup
        FilteredButton                matlab.ui.control.RadioButton
        OriginButton                  matlab.ui.control.RadioButton
        avg_fpsEditField              matlab.ui.control.NumericEditField
        avg_fpsEditFieldLabel         matlab.ui.control.Label
        TimeTagEditField              matlab.ui.control.NumericEditField
        RecorderPanel                 matlab.ui.container.Panel
        StopRecordButton              matlab.ui.control.Button
        StartRecordButton             matlab.ui.control.Button
        RecorderButton                matlab.ui.control.StateButton
        SinButton                     matlab.ui.control.StateButton
        SinFunctionGenPanel           matlab.ui.container.Panel
        f_from_fftEditField           matlab.ui.control.EditField
        f_from_fftEditFieldLabel      matlab.ui.control.Label
        FsEditField                   matlab.ui.control.EditField
        FsEditFieldLabel              matlab.ui.control.Label
        Fcn_TSpinner                  matlab.ui.control.Spinner
        Fcn_TSpinnerLabel             matlab.ui.control.Label
        Fcn_fSpinner                  matlab.ui.control.Spinner
        Fcn_fSpinnerLabel             matlab.ui.control.Label
        Fcn_ASpinner                  matlab.ui.control.Spinner
        Fcn_ASpinnerLabel             matlab.ui.control.Label
        SoundTraceButtonGroup         matlab.ui.container.ButtonGroup
        RightButton                   matlab.ui.control.RadioButton
        LeftButton                    matlab.ui.control.RadioButton
        PlayButton                    matlab.ui.control.StateButton
        PlotButton                    matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        LengthSpinner                 matlab.ui.control.Spinner
        sLabel_2                      matlab.ui.control.Label
        LengthSlider                  matlab.ui.control.Slider
        LengthSliderLabel             matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        TimeSliderLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        AudioTimeEditField            matlab.ui.control.EditField
        AudioTimeEditFieldLabel       matlab.ui.control.Label
        Audio_SampleFsEditField       matlab.ui.control.EditField
        Audio_SampleFsEditFieldLabel  matlab.ui.control.Label
        Video_FPSEditField            matlab.ui.control.EditField
        Video_FPSEditFieldLabel       matlab.ui.control.Label
        Video_HWCEditField            matlab.ui.control.EditField
        Video_HWCLabel                matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel            matlab.ui.control.Label
        FileButton                    matlab.ui.control.Button
        SourceEditField               matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file;
        path;
        file_index;
        is_mp4;
        video = [];
        audio = [];
        audio_filtered = [];
        audio_switch = 0;%在audio和audio_filtered切换
        fs = 0;
        window = [];
        window_length = 1;%窗口长度,s
        current_time = 0;
        audio_left;
        audio_right;
        audio_step = 0.1'%最小播放长度;输入为mp4时为1/fps
        sound_track = 1;
        
        player = [];
        player_data = [];
        state_run = 0;
        %record mode
        state_play = 0;
        use_sound = 0;
        is_recording = 0;
        recorder = [];
        %fft
        %STFT
        view_mode = 1;
    end
    
    methods (Access = private)
        %app.audio,app.fs,app.current_time 
        function flush(app)
            %assignin('base','windowlength',[app.current_time,app.current_time + app.window_length]);
            %assignin('base','current_time',app.current_time);
            %Compoment
            if isempty(app.audio)
                return
            end
            if strcmp(app.TimeSlider.Tag,'connected')
                app.TimeSlider.Limits = [0,max(0.5,length(app.audio)/app.fs)];
                app.TimeSlider.Value = min([app.current_time,length(app.audio)/app.fs]);
                app.TimeTagEditField.Value =app.TimeSlider.Value;
                app.TimeTagEditField.Visible ="on";
                %assignin('base','current',app.current_time);
                %app.TimeSlider.Value = app.current_time;
            end
            app.LengthSpinner.Value = app.window_length;
            app.LengthSlider.Value = log10(app.window_length);
            xlim(app.UIAxes,[app.current_time,app.current_time + app.window_length]);

            l = size(app.audio);
            app.audio_left = max(ceil(app.current_time*app.fs),1);
            app.audio_right = min(floor(app.fs*(app.current_time+app.window_length)),l(1));
            app.window = app.audio( app.audio_left:app.audio_right,app.sound_track);
            
            t = linspace(app.current_time,app.current_time+app.window_length,length(app.window));
            plot(app.UIAxes,t,app.window);
            ylim(app.UIAxes,[min(min(app.window),-0.01),max(max(app.window),0.01)]);
            xlim(app.UIAxes,[app.current_time,app.current_time+app.window_length]);
            %ylim(app.UIAxes,[-1,1]);
            %汉宁窗
            k = 2;
            hanning = hamming(length(app.window));
            hanning = 1;
            k = 1;
            window_fft = fft(app.window.*hanning);
            
            len = floor(length(window_fft)/2);
            assignin('base','len',len);
            f = linspace(0,app.fs/2,len);
            plot(app.UIAxes2,f,k*abs(window_fft(1 : len))/len);
            
            filter_wave = app.Filter(window_fft);
            
            plot(app.UIAxes3 , f , k*abs(filter_wave(1 : len))/len);
            xlim(app.UIAxes2,[0,app.fs/2]);
            xlim(app.UIAxes3,[0,app.fs/2]);
%             ylim(app.UIAxes2,[0,1]);
%             ylim(app.UIAxes3,[0,1]);
            
            plot(app.UIAxes4 , t , real(ifft(filter_wave)));

            ylim(app.UIAxes4,[min(min(app.window),-0.01),max(max(app.window),0.01)]);
            xlim(app.UIAxes4,[app.current_time,app.current_time+app.window_length]);
            assignin('base','fftwave',window_fft);
            assignin('base','fft_filter',filter_wave);

            if app.SinButton.Value == 1
                [~,max_f] =max(abs(window_fft));
                assignin('base','max_f',max_f);
                if max_f >len
                    app.f_from_fftEditField.Value = num2str(f(len));
                else
                    app.f_from_fftEditField.Value = num2str(f(max_f));
                end
            end
%             drawnow;
        end
        function filtered = Filter(app,wave)
            y1 =zeros(1,10);
            N = length(wave);
            y1(1) = app.Slider.Value;
            y1(2) = app.Slider2.Value;
            y1(3) = app.Slider3.Value;
            y1(4) = app.Slider4.Value;
            y1(5) = app.Slider5.Value;
            y1(6) = app.Slider6.Value;
            y1(7) = app.Slider7.Value;
            y1(8) = app.Slider8.Value;
            y1(9) = app.Slider9.Value;
            y1(10) = app.Slider10.Value;
%             for i = 1:10
%                 y1(i) = eval(['app.Slider','0'+i,'.Value']);
%             end
            % 0.01~100倍 -2~2
            y1 = 10.^(y1/50 - 1);
            y1 = [y1(1:9),y1(10),flip(y1(1:9))];
            x1 = ceil(linspace(1,N,19));
            x = linspace(1,N,N);
            assignin('base','app_x1',x1);
            assignin('base','app_y1',y1);
            assignin('base','app_x',x);
            
%             y = interp1(x1,y1,x,'spline');
            s1 = csape(x1,[0,y1,0],'periodic');
            y = fnval(s1,x);
            assignin('base','app_y',y);
            x_label = linspace(1,app.fs,N);
            plot(app.UIAxes5,x_label,y);
            assignin("base",'app_fs',app.fs);
            xlim(app.UIAxes5,[0,app.fs]);
            filtered = y' .*wave;
        end
        function fcn_gen(app)
            Fs = 20000;
            app.fs = Fs;
            app.FsEditField.Value = num2str(Fs);
            T = app.Fcn_TSpinner.Value;
            A = app.Fcn_ASpinner.Value;
            f = app.Fcn_fSpinner.Value;

            t = linspace(0,T,T*Fs);
            app.audio = A * sin(2*pi*f*t)';
            app.audio_filtered = real(ifft(app.Filter(fft(app.audio))));
            %assignin('base','audio',app.audio);
            
            app.RightButton.Visible ='off';
            app.current_time = 0;
            app.player_data = app.audio;
            app.player = audioplayer(app.player_data,app.fs);
            app.sound_track = 1;
            app.TimeSlider.Tag = 'connected';
            app.TimeSlider.Limits = [0 , T];
            app.TimeSlider.UserData = 0;
            %source
            app.SourceEditField.Value = 'Sin Function Generator';
            app.flush();
        end
        function recorder_listener(app)
             if app.is_recording &&~isempty(app.recorder)                                                                          
                app.audio = getaudiodata(app.recorder);
                
                app.window_length = 1;
               %app.current_time = app.current_time+0.1;
               app.current_time = max([0.01,length(app.audio)/app.fs-app.window_length]);
%                assignin('base','recorder',app.recorder);
%                assignin('base','max',[0,length(app.audio)/app.fs -1]);
%                assignin('base','current_time',app.current_time);
               app.flush();
             end
        end
        function safe_ending_recording(app)
            if app.is_recording
                stop(app.recorder);
                app.is_recording = 0;
            end
            app.RecorderButton.Value = 0;
            app.Panel.Visible = 'on';
            app.SinFunctionGenPanel.Visible = "off";
            app.RecorderPanel.Visible = 'off';
            app.RunButton.Visible = "on";
            app.StopButton.Visible ='on';
            app.ResetButton.Visible = 'on';
            app.use_sound = 0;
            app.state_play = 0;
        end
        function safe_ending_fcnGen(app)
            if app.SinButton.Value ==1
                    app.SinButton.Value = 0;
                    app.SinButtonValueChanged();
            end
            app.SinButtonValueChanged();
            app.SinFunctionGenPanel.Visible ="off";
            app.RightButton.Visible ='on';
        end
%         function player_timerfcn(app,player,event)
%             app.current_time = app.player.CurrentSample / app.player.SampleRate;
%             app.flush();
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            
            [app.file,app.path,index] = uigetfile('*.mp4;*.mp3');
             if index==0
                return
             end
             app.Panel.Visible ="on";
             app.safe_ending_recording();
             app.safe_ending_fcnGen();
             text = app.FileButton.Text;
             app.FileButton.Text = 'Loading';
             if strcmp(app.file(length(app.file)-3:length(app.file)),'.mp4')
                 app.is_mp4 = 1;
                 app.video = VideoReader([app.path,app.file]);
                 app.TimeEditField.Value = sprintf('%.2f s',app.video.Duration);
                 app.Video_FPSEditField.Value = sprintf('%.0f',app.video.FrameRate);
                 
                 app.Video_HWCEditField.Value = sprintf('%d×%d×%d',app.video.Height,app.video.Width,app.video.BitsPerPixel/8);
                 app.audio_step = 2/app.video.FrameRate;
                 %app.TimeEditField.Value = num2str( app.video.Duration);
             else
                 app.is_mp4 = 0;
             end
             %audio
              [app.audio,app.fs] = audioread([app.path,app.file]);
              app.player_data = app.audio;
              app.audio_filtered = real(ifft(app.Filter(fft(app.audio))));
              app.Audio_SampleFsEditField.Value = sprintf('%d',app.fs);
              l = size(app.audio);
              app.AudioTimeEditField.Value = sprintf('%.2f',l(1)/app.fs);
              app.LeftButton.Value =1;
              if l(2) ==2
                  app.RightButton.Visible ='on';
              else
                      app.RightButton.Visible ='off';
              end
              app.sound_track = 1;
              %Use audioplayer
              if ~app.use_sound
                  app.player = audioplayer(app.player_data,app.fs);
                  app.PlayButton.Visible ="off";
                  
              end
              %assignin('base','use_sound',app.use_sound)
              %slider
              app.TimeSlider.Tag = 'connected';
              app.TimeSlider.Limits = [0 , l(1)/app.fs];
              app.TimeSlider.UserData = 0;
              %source
              app.SourceEditField.Value = [app.path,app.file];
              app.FileButton.Text = text;
              app.flush();
        end

        % Value changing function: LengthSlider
        function LengthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.window_length = 10^changingValue;
            app.LengthSpinner.Value = 10^changingValue;
            app.flush();
        end

        % Value changing function: LengthSpinner
        function LengthSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.window_length = changingValue;
            app.flush();
        end

        % Value changed function: LengthSpinner
        function LengthSpinnerValueChanged(app, event)
            value = app.LengthSpinner.Value;
            app.window_length = value;
            app.LengthSlider.Value =log10(value);
            app.flush();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.flush();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.audio)
                return
            end
            app.state_run = 1;
%             l = size(app.audio);
%             len = l(1);
%             while app.state_run == 1 && 0
%                 if app.audio_right >= len
%                     app.state_run = 0;
%                     break;
%                 end
%                 step_right = min(floor(app.fs*(app.current_time+app.audio_step)),l(1));
%                 if app.state_play ==1 
%                     sound(app.audio(app.audio_left : step_right,app.sound_track),app.fs);
%                 end
%                 app.current_time = app.current_time + app.audio_step;
%                 app.flush();
%                 pause(app.audio_step);
%             end
%             time = app.window_length/10;
            if ~app.use_sound
                app.player.resume();
                pause(0.05);
                cnt = 0;
                time_avg = 0;
                while   app.state_run && app.player.isplaying() 
                     tic;
                    relative_time = app.player.CurrentSample / app.player.SampleRate;
                    app.current_time = relative_time +app.TimeSlider.UserData;
                    %assignin('base','time',app.current_time);
                    app.flush();

                     if app.audio_switch ==1
                        app.TimeSlider.UserData = app.current_time;
                         stop(app.player);
                        app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
                        app.player.resume();
                        app.audio_switch =0;
                     end
                     time_avg = time_avg *cnt/(cnt+1) +toc/(cnt+1);
                     cnt = (cnt+1)*(cnt<20);
                     app.avg_fpsEditField.Value = 1/time_avg;
%                      if cnt>10
%                          cnt = 0;
%                      end
                     
                     time_avg = time_avg *cnt/(cnt+1) +toc/(cnt+1);
                     cnt = (cnt+1)*(cnt<20);
                     app.avg_fpsEditField.Value = 1/time_avg;
                     pause(0.1);
                end
                if app.player.CurrentSample ==1 &&app.state_run
                        app.current_time = 0;
                        app.TimeSlider.UserData = 0;
                        app.TimeSlider.Value = 0;
                        app.flush();
                        app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
                        assignin('base','in',1);      
                end
                app.state_run = 0;
                assignin('base','player',app.player);
                assignin('base','avg_time',time_avg);
%                 stop(app.player);
%                 app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            end
            app.flush();
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            app.state_play = value;
            app.flush();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if isempty(app.audio)
                return
            end
            if ~app.use_sound
                pause(app.player);
                %Run函数中player.resume()取消更新
                %app.TimeSlider.UserData = app.current_time;
                %app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            end
            app.state_run = 0;
            app.flush();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if isempty(app.audio)
                return
            end
            app.current_time = 0;
            app.state_play = 1;
            app.state_run = 0;
            if ~app.use_sound
                stop(app.player);
                app.current_time = 0;
                app.TimeSlider.UserData = app.current_time;
                app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            end
            app.flush();
        end

        % Selection changed function: SoundTraceButtonGroup
        function SoundTraceButtonGroupSelectionChanged(app, event)
            if app.RightButton.Value == 1
                app.sound_track = 2;
            else
                app.sound_track = 1;
            end
            app.flush();
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            %assignin('base',"event",event)
            app.TimeSlider.Tag = 'changing';
            app.TimeTagEditField.Value =changingValue;
            if app.state_run ==0
                app.current_time = changingValue;
                app.flush();
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            if isempty(app.audio)
                return
            end
            value = app.TimeSlider.Value;
            app.TimeTagEditField.Value =app.TimeSlider.Value;
            app.current_time = value;
            
             if ~app.use_sound %&&~isempty(app.player)
                 stop(app.player);
                 app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
                 app.state_run = 0;
                 app.TimeSlider.UserData = value;
             end
            app.TimeSlider.Tag = 'connected';
            app.flush();
        end

        % Callback function
        function SinButtonPushed(app, event)
%             f = 200;
%             T = 200;
%             A = 1;
%             app.SinFunctionGenPanel.Visible = 'on';
%             app.Panel = 'off';
%             app.fcn_gen(A,f,T);    
        end

        % Value changed function: SinButton
        function SinButtonValueChanged(app, event)
            value = app.SinButton.Value;
            if value == 1
                app.safe_ending_recording();
%                 app.RecorderButton.Value = 0;
%                 app.RecorderButtonValueChanged();
                app.SinFunctionGenPanel.Visible = 'on';
                app.Panel.Visible = 'off';
                app.RecorderPanel.Visible ='off';
                app.SoundTraceButtonGroup.Visible = "off";
                app.fcn_gen();
                app.flush();
                app.use_sound = 0;
                app.state_run = 0;
            else
                app.SinFunctionGenPanel.Visible = 'off';
                app.RecorderPanel.Visible ='off';
                app.Panel.Visible = 'on';
                app.SoundTraceButtonGroup.Visible = "on";
                app.RightButton.Visible ="on";
                app.ResetButtonPushed();
            end
        end

        % Value changed function: Fcn_ASpinner
        function Fcn_ASpinnerValueChanged(app, event)
            app.fcn_gen();
        end

        % Value changed function: Fcn_fSpinner
        function Fcn_fSpinnerValueChanged(app, event)
            app.fcn_gen();
        end

        % Value changed function: Fcn_TSpinner
        function Fcn_TSpinnerValueChanged(app, event)
            app.fcn_gen();
        end

        % Value changed function: RecorderButton
        function RecorderButtonValueChanged(app, event)
            app.fs = 44100;
            %assignin('base','recorder_fs',app.fs);
            
            value = app.RecorderButton.Value;
            app.recorder = audiorecorder(app.fs,16,2,-1);
            app.StopRecordButton.Visible ="off";
            if value ==1
                app.safe_ending_fcnGen();
                app.SourceEditField.Value = 'Recorder';
                app.state_run = 0;
                if app.SinButton.Value ==1
                    app.SinButton.Value = 0;
                    app.SinButtonValueChanged();
                end
                app.Panel.Visible = 'off';
                app.SinFunctionGenPanel.Visible = "off";
                app.RecorderPanel.Visible = 'on';
                app.StartRecordButton.Text = 'Start Record';
            else
                app.safe_ending_recording()
            end
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)
            app.TimeSlider.Tag = 'connected';
            %assignin('base','text',app.StartRecordButton.Text)
            if ~isempty(app.player)
                if app.player.isplaying()
                    stop(app.player);
                    app.current_time = 0;
                end
            end
            app.StopRecordButton.Visible ="on";
            if strcmp(app.StartRecordButton.Text,'Start Record')
                app.StartRecordButton.Text = 'Pause Record';
                app.RunButton.Visible = "off";
                app.StopButton.Visible ='off';
                app.ResetButton.Visible = 'off';
                app.LengthSlider.Value = 1;
                record(app.recorder);
                app.recorder.UserData = 0;
                app.use_sound = 1;
                app.state_play = 0;
                app.is_recording = 1;
                while app.is_recording
                    pause(0.1);
                    app.recorder_listener();
                    
                end
            else 
            %if strcmp(app.StartRecordButton.Text,'Pause Record')
                pause(app.recorder);
                app.StartRecordButton.Text = 'Start Record';
                app.is_recording = 0;
            end
        end

        % Button pushed function: StopRecordButton
        function StopRecordButtonPushed(app, event)
            app.StartRecordButton.Text = 'Start Record';
            app.RunButton.Visible = "on";
            app.StopButton.Visible ='on';
            app.ResetButton.Visible = 'on';
            
            app.audio_filtered = real(ifft(app.Filter(fft(app.audio))));
            app.use_sound = 0;
            app.is_recording = 0;
            app.state_play = 1;
            app.current_time = 0;
            app.TimeSlider.Value =0;
            app.TimeSlider.UserData = app.current_time;
            stop(app.recorder);
            app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            app.TimeSlider.Limits = [0,length(app.audio)/app.fs];
            %%
%             app.current_time = 0;
%             app.state_play = 1;
%             app.state_run = 0;
%             if ~app.use_sound
%                 stop(app.player);
%                 app.current_time = 0;
%                 app.TimeSlider.UserData = app.current_time;
%                 app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
%             end
%             app.flush();
        end

        % Selection changed function: AudioSourceButtonGroup
        function AudioSourceButtonGroupSelectionChanged(app, event)
            if isempty(app.audio)
                return
            end
%             if app.FilteredButton.Value == 1
%                 
%                 if app.state_run == 0
%                     app.player_data = app.audio_filtered;
%                     app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
%                 else
%                     stop(app.player);
%                     app.player_data = app.audio_filtered;
%                     app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
%                     return
%                 end
%             else
%                 if app.state_run == 0
%                     app.player_data = app.audio;
%                     app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
%                 else
%                     stop(app.player);
%                     app.player_data = app.audio;
%                      app.player = audioplayer(app.player_data(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
% 
%                     return
%                 end
%             end


            if app.FilteredButton.Value == 1
                    assignin('base','filter',1);
                    app.player_data = app.audio_filtered;
            else 
                if app.FilteredButton.Value ==0
                    assignin('base','filter',0);
                    app.player_data = app.audio;
                end
            end
            
            app.audio_switch = 1;
        end

        % Value changed function: Slider, Slider10, Slider2, Slider3, 
        % ...and 6 other components
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.AudioSourceButtonGroup.Visible = "on";
            tic;
            app.audio_filtered = real(ifft(app.Filter(fft(app.audio))));
            toc;
            assignin('base','audio_filtered',app.audio_filtered);
            if app.FilteredButton.Value ==1
                app.player_data = app.audio_filtered;
                app.audio_switch =1;
            end
%             app.AudioSourceButtonGroupSelectionChanged();
            app.flush();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始波')
            xlabel(app.UIAxes, 't /s')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'fcn';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'FFT')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '最终波形')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Source';
            app.SourceEditField = uieditfield(app.UIFigure, 'text');
            app.SourceEditField.Editable = 'off';
            app.FileButton = uibutton(app.UIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = 'File';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '文件信息';
            app.TimeEditFieldLabel = uilabel(app.Panel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.Panel, 'text');
            app.TimeEditField.Editable = 'off';
            app.Video_HWCLabel = uilabel(app.Panel);
            app.Video_HWCLabel.Text = 'Video_HWC';
            app.Video_HWCEditField = uieditfield(app.Panel, 'text');
            app.Video_HWCEditField.Editable = 'off';
            app.Video_FPSEditFieldLabel = uilabel(app.Panel);
            app.Video_FPSEditFieldLabel.Text = 'Video_FPS';
            app.Video_FPSEditField = uieditfield(app.Panel, 'text');
            app.Video_FPSEditField.Editable = 'off';
            app.Audio_SampleFsEditFieldLabel = uilabel(app.Panel);
            app.Audio_SampleFsEditFieldLabel.Text = 'Audio_SampleFs';
            app.Audio_SampleFsEditField = uieditfield(app.Panel, 'text');
            app.Audio_SampleFsEditField.Editable = 'off';
            app.AudioTimeEditFieldLabel = uilabel(app.Panel);
            app.AudioTimeEditFieldLabel.Text = 'AudioTime';
            app.AudioTimeEditField = uieditfield(app.Panel, 'text');
            app.AudioTimeEditField.Editable = 'off';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.LengthSliderLabel = uilabel(app.UIFigure);
            app.LengthSliderLabel.Text = 'Length';
            app.LengthSlider = uislider(app.UIFigure);
            app.LengthSlider.Limits = [-1 1];
            app.LengthSlider.ValueChangingFcn = createCallbackFcn(app, @LengthSliderValueChanging, true);
            app.LengthSlider.Value = 1;
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = 's';
            app.LengthSpinner = uispinner(app.UIFigure);
            app.LengthSpinner.ValueChangingFcn = createCallbackFcn(app, @LengthSpinnerValueChanging, true);
            app.LengthSpinner.Limits = [0.1 10];
            app.LengthSpinner.ValueChangedFcn = createCallbackFcn(app, @LengthSpinnerValueChanged, true);
            app.LengthSpinner.Value = 10;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.SoundTraceButtonGroup = uibuttongroup(app.UIFigure);
            app.SoundTraceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SoundTraceButtonGroupSelectionChanged, true);
            app.SoundTraceButtonGroup.Title = 'SoundTrace';
            app.LeftButton = uiradiobutton(app.SoundTraceButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.SoundTraceButtonGroup);
            app.RightButton.Text = 'Right';
            app.SinFunctionGenPanel = uipanel(app.UIFigure);
            app.SinFunctionGenPanel.Title = 'Sin Function Gen';
            app.SinFunctionGenPanel.Visible = 'off';
            app.Fcn_ASpinnerLabel = uilabel(app.SinFunctionGenPanel);
            app.Fcn_ASpinnerLabel.Text = 'Fcn_A';
            app.Fcn_ASpinner = uispinner(app.SinFunctionGenPanel);
            app.Fcn_ASpinner.Limits = [0 1];
            app.Fcn_ASpinner.ValueChangedFcn = createCallbackFcn(app, @Fcn_ASpinnerValueChanged, true);
            app.Fcn_ASpinner.Value = 1;
            app.Fcn_fSpinnerLabel = uilabel(app.SinFunctionGenPanel);
            app.Fcn_fSpinnerLabel.Text = 'Fcn_f';
            app.Fcn_fSpinner = uispinner(app.SinFunctionGenPanel);
            app.Fcn_fSpinner.Step = 10;
            app.Fcn_fSpinner.Limits = [0 10000];
            app.Fcn_fSpinner.ValueChangedFcn = createCallbackFcn(app, @Fcn_fSpinnerValueChanged, true);
            app.Fcn_fSpinner.Value = 200;
            app.Fcn_TSpinnerLabel = uilabel(app.SinFunctionGenPanel);
            app.Fcn_TSpinnerLabel.Text = 'Fcn_T';
            app.Fcn_TSpinner = uispinner(app.SinFunctionGenPanel);
            app.Fcn_TSpinner.Limits = [0 1000];
            app.Fcn_TSpinner.ValueChangedFcn = createCallbackFcn(app, @Fcn_TSpinnerValueChanged, true);
            app.Fcn_TSpinner.Value = 200;
            app.FsEditFieldLabel = uilabel(app.SinFunctionGenPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SinFunctionGenPanel, 'text');
            app.FsEditField.Editable = 'off';
            app.f_from_fftEditFieldLabel = uilabel(app.SinFunctionGenPanel);
            app.f_from_fftEditFieldLabel.Text = 'f_from_fft';
            app.f_from_fftEditField = uieditfield(app.SinFunctionGenPanel, 'text');
            app.f_from_fftEditField.Editable = 'off';
            app.SinButton = uibutton(app.UIFigure, 'state');
            app.SinButton.ValueChangedFcn = createCallbackFcn(app, @SinButtonValueChanged, true);
            app.SinButton.Text = 'Sin';
            app.RecorderButton = uibutton(app.UIFigure, 'state');
            app.RecorderButton.ValueChangedFcn = createCallbackFcn(app, @RecorderButtonValueChanged, true);
            app.RecorderButton.Text = 'Recorder';
            app.RecorderPanel = uipanel(app.UIFigure);
            app.RecorderPanel.Title = 'Recorder';
            app.RecorderPanel.Visible = 'off';
            app.StartRecordButton = uibutton(app.RecorderPanel, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = 'Start Record';
            app.StopRecordButton = uibutton(app.RecorderPanel, 'push');
            app.StopRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButtonPushed, true);
            app.StopRecordButton.Text = 'Stop Record';
            app.TimeTagEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeTagEditField.Editable = 'off';
            app.TimeTagEditField.Visible = 'off';
            app.avg_fpsEditFieldLabel = uilabel(app.UIFigure);
            app.avg_fpsEditFieldLabel.Text = 'avg_fps';
            app.avg_fpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AudioSourceButtonGroup = uibuttongroup(app.UIFigure);
            app.AudioSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AudioSourceButtonGroupSelectionChanged, true);
            app.AudioSourceButtonGroup.Title = 'AudioSource';
            app.OriginButton = uiradiobutton(app.AudioSourceButtonGroup);
            app.OriginButton.Text = 'Origin';
            app.OriginButton.Value = true;
            app.FilteredButton = uiradiobutton(app.AudioSourceButtonGroup);
            app.FilteredButton.Text = 'Filtered';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.FilterPanel = uipanel(app.Tab);
            app.FilterPanel.Title = 'FilterPanel';
            app.Slider = uislider(app.FilterPanel);
            app.Slider.MajorTicks = [0 20 40 60 80 100];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 50;
            app.Slider2 = uislider(app.FilterPanel);
            app.Slider2.MajorTickLabels = {''};
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2.Value = 50;
            app.Slider3 = uislider(app.FilterPanel);
            app.Slider3.MajorTickLabels = {''};
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider3.Value = 50;
            app.Slider4 = uislider(app.FilterPanel);
            app.Slider4.MajorTickLabels = {''};
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider4.Value = 50;
            app.Slider5 = uislider(app.FilterPanel);
            app.Slider5.MajorTickLabels = {''};
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider5.Value = 50;
            app.Slider6 = uislider(app.FilterPanel);
            app.Slider6.MajorTickLabels = {''};
            app.Slider6.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider6.Value = 50;
            app.Slider7 = uislider(app.FilterPanel);
            app.Slider7.MajorTickLabels = {''};
            app.Slider7.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider7.Value = 50;
            app.Slider8 = uislider(app.FilterPanel);
            app.Slider8.MajorTickLabels = {''};
            app.Slider8.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider8.Value = 50;
            app.Slider9 = uislider(app.FilterPanel);
            app.Slider9.MajorTickLabels = {''};
            app.Slider9.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider9.Value = 50;
            app.Slider10 = uislider(app.FilterPanel);
            app.Slider10.MajorTickLabels = {''};
            app.Slider10.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider10.Value = 50;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes5 = uiaxes(app.Tab2);
            title(app.UIAxes5, 'Filter')
            xlabel(app.UIAxes5, 'F')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Media_Player_Filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end