classdef Media_Player_STFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        time_mewsEditField            matlab.ui.control.NumericEditField
        time_mewsEditFieldLabel       matlab.ui.control.Label
        time_stftEditField            matlab.ui.control.NumericEditField
        time_stftEditFieldLabel       matlab.ui.control.Label
        avg_fpsEditField              matlab.ui.control.NumericEditField
        avg_fpsEditFieldLabel         matlab.ui.control.Label
        ViewDropDown                  matlab.ui.control.DropDown
        ViewDropDownLabel             matlab.ui.control.Label
        TimeTagEditField              matlab.ui.control.NumericEditField
        TimeTagEditFieldLabel         matlab.ui.control.Label
        RecorderPanel                 matlab.ui.container.Panel
        StopRecordButton              matlab.ui.control.Button
        StartRecordButton             matlab.ui.control.Button
        RecorderButton                matlab.ui.control.StateButton
        SinButton                     matlab.ui.control.StateButton
        SinFunctionGenPanel           matlab.ui.container.Panel
        f_from_fftEditField           matlab.ui.control.EditField
        f_from_fftEditFieldLabel      matlab.ui.control.Label
        FsEditField                   matlab.ui.control.EditField
        FsEditFieldLabel              matlab.ui.control.Label
        Fcn_TSpinner                  matlab.ui.control.Spinner
        Fcn_TSpinnerLabel             matlab.ui.control.Label
        Fcn_fSpinner                  matlab.ui.control.Spinner
        Fcn_fSpinnerLabel             matlab.ui.control.Label
        Fcn_ASpinner                  matlab.ui.control.Spinner
        Fcn_ASpinnerLabel             matlab.ui.control.Label
        SoundTraceButtonGroup         matlab.ui.container.ButtonGroup
        RightButton                   matlab.ui.control.RadioButton
        LeftButton                    matlab.ui.control.RadioButton
        PlayButton                    matlab.ui.control.StateButton
        PlotButton                    matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        LengthSpinner                 matlab.ui.control.Spinner
        sLabel_2                      matlab.ui.control.Label
        WindowLengthSlider            matlab.ui.control.Slider
        WindowLengthSliderLabel       matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        TimeSliderLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        AudioTimeEditField            matlab.ui.control.EditField
        AudioTimeEditFieldLabel       matlab.ui.control.Label
        Audio_SampleFsEditField       matlab.ui.control.EditField
        Audio_SampleFsEditFieldLabel  matlab.ui.control.Label
        Video_FPSEditField            matlab.ui.control.EditField
        Video_FPSEditFieldLabel       matlab.ui.control.Label
        Video_HWCEditField            matlab.ui.control.EditField
        Video_HWCLabel                matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel            matlab.ui.control.Label
        FileButton                    matlab.ui.control.Button
        SourceEditField               matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file;
        path;
        file_index;
        is_mp4;
        video = [];
        audio = [];
        fs = 0;
        window = [];
        window_length = 1;%窗口长度,s
        current_time = 0;
        audio_left;
        audio_right;
        audio_step = 0.1'%最小播放长度;输入为mp4时为1/fps
        sound_track = 1;
        
        player = [];
        
        state_run = 0;
        %record mode
        state_play = 0;
        use_sound = 0;
        is_recording = 0;
        recorder = [];
        %fft
        %STFT
        view_mode = 1;
    end
    
    methods (Access = private)
        %app.audio,app.fs,app.current_time 
        function flush(app)
            %assignin('base','windowlength',[app.current_time,app.current_time + app.window_length]);
            %assignin('base','current_time',app.current_time);
            %Compoment
            if isempty(app.audio)
                return
            end
            if strcmp(app.TimeSlider.Tag,'connected')
                app.TimeSlider.Limits = [0,max(0.5,length(app.audio)/app.fs)];
                app.TimeSlider.Value = min([app.current_time,length(app.audio)/app.fs]);
                app.TimeTagEditField.Value =app.TimeSlider.Value;
                app.TimeTagEditField.Visible ="on";
                app.TimeTagEditFieldLabel.Visible ='off';
                %assignin('base','current',app.current_time);
                %app.TimeSlider.Value = app.current_time;
            end
            app.LengthSpinner.Value = app.window_length;
            app.WindowLengthSlider.Value = log10(app.window_length);
            xlim(app.UIAxes,[app.current_time,app.current_time + app.window_length]);

            l = size(app.audio);
            app.audio_left = max(ceil(app.current_time*app.fs),1);
            app.audio_right = min(floor(app.fs*(app.current_time+app.window_length)),l(1));
            app.window = app.audio( app.audio_left:app.audio_right,app.sound_track);
            
            t = linspace(app.current_time,app.current_time+app.window_length,length(app.window));
            plot(app.UIAxes,t,app.window);
            ylim(app.UIAxes,[min(min(app.window),-0.01),max(max(app.window),0.01)]);
            
%             %ylim(app.UIAxes,[-1,1]);
%             %汉宁窗
%             k = 2;
%             hanning = hamming(length(app.window));
           
            % hanning = 1;
            
            %window_fft = fft(app.window.*hanning);
            
%             len = floor(length(window_fft)/2);
%             f = linspace(0,app.fs/2,len);
%             plot(app.UIAxes2,f,k*abs(window_fft(1 : len))/len);
%             plot(app.UIAxes3,f,angle(window_fft(1:len))*180/pi);
%             xlim(app.UIAxes2,[0,app.fs/2]);
%             xlim(app.UIAxes3,[0,app.fs/2]);
%             %ylim(app.UIAxes2,[-1,1]);
%             %STFT
            
            %ylim(app.UIAxes3,[-180,180]);
%             if app.SinButton.Value == 1
%                 [~,max_f] =max(abs(window_fft));
%                 app.f_from_fftEditField.Value = num2str(f(max_f));
%             end
            N = 256;
            tic;
            [s,f,t] = stft(app.window,app.fs,FFTLength=2*N);
            assignin('base','app_f',f);
            assignin('base','app_t',t);
            abs_s = abs(s);
            a = toc;
            assignin('base',"time_stft",a);
            app.time_stftEditField.Value = a;
            tic;
            mesh(app.UIAxes2,t,f(f>0),abs_s(f>0,:)/32);
            xlabel(app.UIAxes2,'t / s');
            ylabel(app.UIAxes2,'f / Hz');
            ylim(app.UIAxes2,[0,app.fs/2]);
            xlim(app.UIAxes2,[0,t(length(t))]);
            zlim(app.UIAxes2,[0,1]);
            switch app.view_mode
                    
                case 2 % view from f axes show t axes
                    view(app.UIAxes2,[0,-1,0]);
                case 3% view from t axes show f axes
                    view(app.UIAxes2,[1,0,0]);
                case 4
                    view(app.UIAxes2,[0,0,1]);
                otherwise
                    view(app.UIAxes2,[1,1,1]);
            end
%             colorbar(app.UIAxes2);

            drawnow;
            b = toc;
            app.time_mewsEditField.Value = b;
            assignin('base',"time_mess",b);
        end
        function fcn_gen(app)
            Fs = 1024*32;
            app.FsEditField.Value = num2str(Fs);
            T = app.Fcn_TSpinner.Value;
            A = app.Fcn_ASpinner.Value;
            f = app.Fcn_fSpinner.Value;

            t = linspace(0,T,T*Fs);
            app.audio = A * sin(2*pi*f*t)';

            %assignin('base','audio',app.audio);
            app.fs = Fs;
            app.RightButton.Visible ='off';
            app.current_time = 0;

            app.player = audioplayer(app.audio,app.fs);
            app.sound_track = 1;
            app.TimeSlider.Tag = 'connected';
            app.TimeSlider.Limits = [0 , T];
            app.TimeSlider.UserData = 0;
            %source
            app.SourceEditField.Value = 'Sin Function Generator';
            app.flush();
        end
        function recorder_listener(app)
             if app.is_recording &&~isempty(app.recorder)                                                                          
                app.audio = getaudiodata(app.recorder);
                app.window_length = 1;
               %app.current_time = app.current_time+0.1;
               app.current_time = max([0.01,length(app.audio)/app.fs-app.window_length]);
%                assignin('base','recorder',app.recorder);
%                assignin('base','max',[0,length(app.audio)/app.fs -1]);
%                assignin('base','current_time',app.current_time);
               app.flush();
             end
        end
        function safe_ending_recording(app)
            if app.is_recording
                stop(app.recorder);
                app.is_recording = 0;
            end
            app.RecorderButton.Value = 0;
            app.Panel.Visible = 'on';
            app.SinFunctionGenPanel.Visible = "off";
            app.RecorderPanel.Visible = 'off';
            app.RunButton.Visible = "on";
            app.StopButton.Visible ='on';
            app.ResetButton.Visible = 'on';
            app.use_sound = 0;
            app.state_play = 0;
        end
        function safe_ending_fcnGen(app)
            if app.SinButton.Value ==1
                    app.SinButton.Value = 0;
                    app.SinButtonValueChanged();
            end
            app.SinButtonValueChanged();
            app.SinFunctionGenPanel.Visible ="off";
            app.RightButton.Visible ='on';
        end
%         function player_timerfcn(app,player,event)
%             app.current_time = app.player.CurrentSample / app.player.SampleRate;
%             app.flush();
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            
            [app.file,app.path,index] = uigetfile('*.mp4;*.mp3');
             if index==0
                return
             end
             app.Panel.Visible ="on";
             app.safe_ending_recording();
             app.safe_ending_fcnGen();
             text = app.FileButton.Text;
             app.FileButton.Text = 'Loading';
             if strcmp(app.file(length(app.file)-3:length(app.file)),'.mp4')
                 app.is_mp4 = 1;
                 app.video = VideoReader([app.path,app.file]);
                 app.TimeEditField.Value = sprintf('%.2f s',app.video.Duration);
                 app.Video_FPSEditField.Value = sprintf('%.0f',app.video.FrameRate);
                 
                 app.Video_HWCEditField.Value = sprintf('%d×%d×%d',app.video.Height,app.video.Width,app.video.BitsPerPixel/8);
                 app.audio_step = 2/app.video.FrameRate;
                 %app.TimeEditField.Value = num2str( app.video.Duration);
             else
                 app.is_mp4 = 0;
             end
             %audio
              [app.audio,app.fs] = audioread([app.path,app.file]);
              app.Audio_SampleFsEditField.Value = sprintf('%d',app.fs);
              l = size(app.audio);
              app.AudioTimeEditField.Value = sprintf('%.2f',l(1)/app.fs);
              app.LeftButton.Value =1;
              if l(2) ==2
                  app.RightButton.Visible ='on';
              else
                      app.RightButton.Visible ='off';
              end
              app.sound_track = 1;
              %Use audioplayer
              if ~app.use_sound
                  app.player = audioplayer(app.audio,app.fs);
                  app.PlayButton.Visible ="off";
                  
              end
              %assignin('base','use_sound',app.use_sound)
              %slider
              app.TimeSlider.Tag = 'connected';
              app.TimeSlider.Limits = [0 , l(1)/app.fs];
              app.TimeSlider.UserData = 0;
              %source
              app.SourceEditField.Value = [app.path,app.file];
              app.FileButton.Text = text;
              app.flush();
        end

        % Value changing function: WindowLengthSlider
        function WindowLengthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.window_length = 10^changingValue;
            app.LengthSpinner.Value = 10^changingValue;
            app.flush();
        end

        % Value changing function: LengthSpinner
        function LengthSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.window_length = changingValue;
            app.flush();
        end

        % Value changed function: LengthSpinner
        function LengthSpinnerValueChanged(app, event)
            value = app.LengthSpinner.Value;
            app.window_length = value;
            app.WindowLengthSlider.Value =log10(value);
            app.flush();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.flush();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.audio)
                return
            end
            app.state_run = 1;
%             l = size(app.audio);
%             len = l(1);
%             while app.state_run == 1 && 0
%                 if app.audio_right >= len
%                     app.state_run = 0;
%                     break;
%                 end
%                 step_right = min(floor(app.fs*(app.current_time+app.audio_step)),l(1));
%                 if app.state_play ==1 
%                     sound(app.audio(app.audio_left : step_right,app.sound_track),app.fs);
%                 end
%                 app.current_time = app.current_time + app.audio_step;
%                 app.flush();
%                 pause(app.audio_step);
%             end
%             time = app.window_length/10;
            if ~app.use_sound
                app.player.resume();
                pause(0.05);
                cnt = 0;
                time_avg = 0;
                while   app.state_run && app.player.isplaying() 
                    tic;
                    relative_time = app.player.CurrentSample / app.player.SampleRate;
                    app.current_time = relative_time +app.TimeSlider.UserData;
                    %assignin('base','time',app.current_time);
                    app.flush();
                     if app.current_time >=length(app.audio)/app.fs - 0.1
                         % || app.player.CurrentSample >= app.player.TotalSamples - app.player.SampleRate*0.1
                        app.current_time = 0;
                        app.TimeSlider.UserData = 0;
                        app.player = audioplayer(app.audio(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
                        break
                     end
                     time_avg = time_avg *cnt/(cnt+1) +toc/(cnt+1);
                     cnt = (cnt+1)*(cnt<20);
                     app.avg_fpsEditField.Value = 1/time_avg;
%                      if cnt>10
%                          cnt = 0;
%                      end
%                     pause(time);
                end
                assignin('base','avg_time',time_avg);
%                 stop(app.player);
%                 app.player = audioplayer(app.audio(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            end
            app.flush();
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            app.state_play = value;
            app.flush();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if isempty(app.audio)
                return
            end
            if ~app.use_sound
                pause(app.player);
                %Run函数中player.resume()取消更新
                %app.TimeSlider.UserData = app.current_time;
                %app.player = audioplayer(app.audio(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            end
            app.state_run = 0;
            app.flush();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if isempty(app.audio)
                return
            end
            app.current_time = 0;
            app.state_play = 1;
            app.state_run = 0;
            if ~app.use_sound
                stop(app.player);
                app.current_time = 0;
                app.TimeSlider.UserData = app.current_time;
                app.player = audioplayer(app.audio(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            end
            app.flush();
        end

        % Selection changed function: SoundTraceButtonGroup
        function SoundTraceButtonGroupSelectionChanged(app, event)
            if app.RightButton.Value == 1
                app.sound_track = 2;
            else
                app.sound_track = 1;
            end
            app.flush();
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            %assignin('base',"event",event)
            app.TimeSlider.Tag = 'changing';
            app.TimeTagEditField.Value =changingValue;
            if app.state_run ==0
                app.current_time = changingValue;
                app.flush();
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            if isempty(app.audio)
                return
            end
            value = app.TimeSlider.Value;
            app.TimeTagEditField.Value =app.TimeSlider.Value;
            app.current_time = value;
            
             if ~app.use_sound %&&~isempty(app.player)
                 stop(app.player);
                 app.player = audioplayer(app.audio(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
                 app.TimeSlider.UserData = value;
             end
            app.TimeSlider.Tag = 'connected';
            app.flush();
        end

        % Callback function
        function SinButtonPushed(app, event)
            f = 200;
            T = 200;
            A = 1;
            app.SinFunctionGenPanel.Visible = 'on';
            app.Panel = 'off';
            app.fcn_gen(A,f,T);    
        end

        % Value changed function: SinButton
        function SinButtonValueChanged(app, event)
            value = app.SinButton.Value;
            if value == 1
                app.safe_ending_recording();
%                 app.RecorderButton.Value = 0;
%                 app.RecorderButtonValueChanged();
                app.SinFunctionGenPanel.Visible = 'on';
                app.Panel.Visible = 'off';
                app.RecorderPanel.Visible ='off';
                app.SoundTraceButtonGroup.Visible = "off";
                app.fcn_gen();
                app.flush();
                app.use_sound = 0;
                app.state_run = 0;
            else
                app.SinFunctionGenPanel.Visible = 'off';
                app.RecorderPanel.Visible ='off';
                app.Panel.Visible = 'on';
                app.SoundTraceButtonGroup.Visible = "on";
                app.RightButton.Visible ="on";
                app.ResetButtonPushed();
            end
        end

        % Value changed function: Fcn_ASpinner
        function Fcn_ASpinnerValueChanged(app, event)
            app.fcn_gen();
        end

        % Value changed function: Fcn_fSpinner
        function Fcn_fSpinnerValueChanged(app, event)
            app.fcn_gen();
        end

        % Value changed function: Fcn_TSpinner
        function Fcn_TSpinnerValueChanged(app, event)
            app.fcn_gen();
        end

        % Value changed function: RecorderButton
        function RecorderButtonValueChanged(app, event)
            app.fs = 44100;
            %assignin('base','recorder_fs',app.fs);
            
            value = app.RecorderButton.Value;
            app.recorder = audiorecorder(app.fs,16,2,-1);
            app.StopRecordButton.Visible ="off";
            if value ==1
                app.safe_ending_fcnGen();
                app.SourceEditField.Value = 'Recorder';
                app.state_run = 0;
                if app.SinButton.Value ==1
                    app.SinButton.Value = 0;
                    app.SinButtonValueChanged();
                end
                app.Panel.Visible = 'off';
                app.SinFunctionGenPanel.Visible = "off";
                app.RecorderPanel.Visible = 'on';
                app.StartRecordButton.Text = 'Start Record';
            else
                app.safe_ending_recording()
            end
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)
            app.TimeSlider.Tag = 'connected';
            %assignin('base','text',app.StartRecordButton.Text)
            if ~isempty(app.player)
                if app.player.isplaying()
                    stop(app.player);
                    app.current_time = 0;
                end
            end
            app.StopRecordButton.Visible ="on";
            if strcmp(app.StartRecordButton.Text,'Start Record')
                app.StartRecordButton.Text = 'Pause Record';
                app.RunButton.Visible = "off";
                app.StopButton.Visible ='off';
                app.ResetButton.Visible = 'off';
                app.WindowLengthSlider.Value = 1;
                record(app.recorder);
                app.recorder.UserData = 0;
                app.use_sound = 1;
                app.state_play = 0;
                app.is_recording = 1;
                while app.is_recording
                    pause(0.1);
                    app.recorder_listener();
                    
                end
            else 
            %if strcmp(app.StartRecordButton.Text,'Pause Record')
                pause(app.recorder);
                app.StartRecordButton.Text = 'Start Record';
                app.is_recording = 0;
            end
        end

        % Button pushed function: StopRecordButton
        function StopRecordButtonPushed(app, event)
            app.StartRecordButton.Text = 'Start Record';
            app.RunButton.Visible = "on";
            app.StopButton.Visible ='on';
            app.ResetButton.Visible = 'on';

            app.use_sound = 0;
            app.is_recording = 0;
            app.state_play = 1;
            app.current_time = 0;
            app.TimeSlider.Value =0;
            app.TimeSlider.UserData = app.current_time;
            stop(app.recorder);
            app.player = audioplayer(app.audio(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
            app.TimeSlider.Limits = [0,length(app.audio)/app.fs];
            %%
%             app.current_time = 0;
%             app.state_play = 1;
%             app.state_run = 0;
%             if ~app.use_sound
%                 stop(app.player);
%                 app.current_time = 0;
%                 app.TimeSlider.UserData = app.current_time;
%                 app.player = audioplayer(app.audio(max(ceil(app.current_time*app.fs),1):length(app.audio),:),app.fs);
%             end
%             app.flush();
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            app.view_mode = value - '0';
            app.flush();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fcn')
            xlabel(app.UIAxes, 't /s')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'fcn';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'STFT')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Source';
            app.SourceEditField = uieditfield(app.UIFigure, 'text');
            app.SourceEditField.Editable = 'off';
            app.FileButton = uibutton(app.UIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = 'File';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '文件信息';
            app.TimeEditFieldLabel = uilabel(app.Panel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.Panel, 'text');
            app.TimeEditField.Editable = 'off';
            app.Video_HWCLabel = uilabel(app.Panel);
            app.Video_HWCLabel.Text = 'Video_HWC';
            app.Video_HWCEditField = uieditfield(app.Panel, 'text');
            app.Video_HWCEditField.Editable = 'off';
            app.Video_FPSEditFieldLabel = uilabel(app.Panel);
            app.Video_FPSEditFieldLabel.Text = 'Video_FPS';
            app.Video_FPSEditField = uieditfield(app.Panel, 'text');
            app.Video_FPSEditField.Editable = 'off';
            app.Audio_SampleFsEditFieldLabel = uilabel(app.Panel);
            app.Audio_SampleFsEditFieldLabel.Text = 'Audio_SampleFs';
            app.Audio_SampleFsEditField = uieditfield(app.Panel, 'text');
            app.Audio_SampleFsEditField.Editable = 'off';
            app.AudioTimeEditFieldLabel = uilabel(app.Panel);
            app.AudioTimeEditFieldLabel.Text = 'AudioTime';
            app.AudioTimeEditField = uieditfield(app.Panel, 'text');
            app.AudioTimeEditField.Editable = 'off';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.WindowLengthSliderLabel = uilabel(app.UIFigure);
            app.WindowLengthSliderLabel.Text = 'Window Length';
            app.WindowLengthSlider = uislider(app.UIFigure);
            app.WindowLengthSlider.Limits = [-1 1];
            app.WindowLengthSlider.ValueChangingFcn = createCallbackFcn(app, @WindowLengthSliderValueChanging, true);
            app.WindowLengthSlider.Value = 1;
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = 's';
            app.LengthSpinner = uispinner(app.UIFigure);
            app.LengthSpinner.ValueChangingFcn = createCallbackFcn(app, @LengthSpinnerValueChanging, true);
            app.LengthSpinner.Limits = [0.1 10];
            app.LengthSpinner.ValueChangedFcn = createCallbackFcn(app, @LengthSpinnerValueChanged, true);
            app.LengthSpinner.Value = 10;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.SoundTraceButtonGroup = uibuttongroup(app.UIFigure);
            app.SoundTraceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SoundTraceButtonGroupSelectionChanged, true);
            app.SoundTraceButtonGroup.Title = 'SoundTrace';
            app.LeftButton = uiradiobutton(app.SoundTraceButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.SoundTraceButtonGroup);
            app.RightButton.Text = 'Right';
            app.SinFunctionGenPanel = uipanel(app.UIFigure);
            app.SinFunctionGenPanel.Title = 'Sin Function Gen';
            app.SinFunctionGenPanel.Visible = 'off';
            app.Fcn_ASpinnerLabel = uilabel(app.SinFunctionGenPanel);
            app.Fcn_ASpinnerLabel.Text = 'Fcn_A';
            app.Fcn_ASpinner = uispinner(app.SinFunctionGenPanel);
            app.Fcn_ASpinner.Limits = [0 1];
            app.Fcn_ASpinner.ValueChangedFcn = createCallbackFcn(app, @Fcn_ASpinnerValueChanged, true);
            app.Fcn_ASpinner.Value = 1;
            app.Fcn_fSpinnerLabel = uilabel(app.SinFunctionGenPanel);
            app.Fcn_fSpinnerLabel.Text = 'Fcn_f';
            app.Fcn_fSpinner = uispinner(app.SinFunctionGenPanel);
            app.Fcn_fSpinner.Step = 10;
            app.Fcn_fSpinner.Limits = [0 10000];
            app.Fcn_fSpinner.ValueChangedFcn = createCallbackFcn(app, @Fcn_fSpinnerValueChanged, true);
            app.Fcn_fSpinner.Value = 200;
            app.Fcn_TSpinnerLabel = uilabel(app.SinFunctionGenPanel);
            app.Fcn_TSpinnerLabel.Text = 'Fcn_T';
            app.Fcn_TSpinner = uispinner(app.SinFunctionGenPanel);
            app.Fcn_TSpinner.Limits = [0 1000];
            app.Fcn_TSpinner.ValueChangedFcn = createCallbackFcn(app, @Fcn_TSpinnerValueChanged, true);
            app.Fcn_TSpinner.Value = 200;
            app.FsEditFieldLabel = uilabel(app.SinFunctionGenPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SinFunctionGenPanel, 'text');
            app.FsEditField.Editable = 'off';
            app.f_from_fftEditFieldLabel = uilabel(app.SinFunctionGenPanel);
            app.f_from_fftEditFieldLabel.Text = 'f_from_fft';
            app.f_from_fftEditField = uieditfield(app.SinFunctionGenPanel, 'text');
            app.f_from_fftEditField.Editable = 'off';
            app.SinButton = uibutton(app.UIFigure, 'state');
            app.SinButton.ValueChangedFcn = createCallbackFcn(app, @SinButtonValueChanged, true);
            app.SinButton.Text = 'Sin';
            app.RecorderButton = uibutton(app.UIFigure, 'state');
            app.RecorderButton.ValueChangedFcn = createCallbackFcn(app, @RecorderButtonValueChanged, true);
            app.RecorderButton.Text = 'Recorder';
            app.RecorderPanel = uipanel(app.UIFigure);
            app.RecorderPanel.Title = 'Recorder';
            app.RecorderPanel.Visible = 'off';
            app.StartRecordButton = uibutton(app.RecorderPanel, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = 'Start Record';
            app.StopRecordButton = uibutton(app.RecorderPanel, 'push');
            app.StopRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButtonPushed, true);
            app.StopRecordButton.Text = 'Stop Record';
            app.TimeTagEditFieldLabel = uilabel(app.UIFigure);
            app.TimeTagEditFieldLabel.Visible = 'off';
            app.TimeTagEditFieldLabel.Text = 'TimeTag';
            app.TimeTagEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeTagEditField.Editable = 'off';
            app.TimeTagEditField.Visible = 'off';
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'default', 't', 'f', 's'};
            app.ViewDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = '1';
            app.avg_fpsEditFieldLabel = uilabel(app.UIFigure);
            app.avg_fpsEditFieldLabel.Text = 'avg_fps';
            app.avg_fpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.time_stftEditFieldLabel = uilabel(app.UIFigure);
            app.time_stftEditFieldLabel.Text = 'time_stft';
            app.time_stftEditField = uieditfield(app.UIFigure, 'numeric');
            app.time_mewsEditFieldLabel = uilabel(app.UIFigure);
            app.time_mewsEditFieldLabel.Text = 'time_mews';
            app.time_mewsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Media_Player_STFT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end