classdef Modem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        fSpinner           matlab.ui.control.Spinner
        fSpinnerLabel      matlab.ui.control.Label
        ModeDropDown       matlab.ui.control.DropDown
        ModeDropDownLabel  matlab.ui.control.Label
        PlotButton         matlab.ui.control.Button
        UIAxes4            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        f = 10;
        Fs = 44100;
        Fc = 2000;
        freqdev = 100;
%         Frequency deviation of the modulated signal in hertz (Hz), specified as a positive real scalar.
         phasedev = pi;
%         phase deviation, specified as a positive scalar in radians.
        T = 1;
        x;
        y;
        mode = 1;
%         mod_fcn = ["ammod","fmmod","pmmod"];
%         demod_fcn = ["amdemod","fmdemod","pmdemod"];
    end
    
    methods (Access = private)
        
        function plotAxes(app)
            if isempty(app.x)
                return
            end
            plot(app.UIAxes,app.x,app.y);
            plot(app.UIAxes2,app.x,app.y);

%             mod_data = feval(app.mod_fcn(app.mode),app.y,app.Fc,app.Fs);
%             demod_data = feval(app.demod_fcn(app.mode),mod_data,app.Fc,app.Fs);
            switch(app.mode)
                case 1
                    mod_data = ammod(app.y,app.Fc,app.Fs);
                    demod_data = amdemod(mod_data,app.Fc,app.Fs);
                case 2
                    mod_data = fmmod(app.y,app.Fc,app.Fs,app.freqdev);
                    demod_data = fmdemod(mod_data,app.Fc,app.Fs,app.freqdev);
                case 3
                    mod_data = fmmod(app.y,app.Fc,app.Fs,app.phasedev);
                    demod_data = fmdemod(mod_data,app.Fc,app.Fs,app.phasedev);
            end
            
            demod_data_f = fft(demod_data);
            demod_data_f = abs(demod_data_f) / (app.T * app.Fs /2);
            fft_x = linspace(0,app.Fs,length(app.y));

            plot(app.UIAxes2,app.x,mod_data);
            plot(app.UIAxes3,app.x,demod_data);
            plot(app.UIAxes4,fft_x,demod_data_f);
            xlim(app.UIAxes4,[0,1000]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.x = linspace(0,app.T,app.T*app.Fs);
            app.y = sin(2*pi*app.f*app.x);
            app.plotAxes();
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            assignin('base','mode',value);
            app.mode = value;
            app.plotAxes();
        end

        % Value changed function: fSpinner
        function fSpinnerValueChanged(app, event)
            value = app.fSpinner.Value;
            app.f = value;
            app.PlotButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始数据')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '调制数据')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '调解数据')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '调解数据波形')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'AM', 'FM', 'PM'};
            app.ModeDropDown.ItemsData = [1 2 3];
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 1;
            app.fSpinnerLabel = uilabel(app.UIFigure);
            app.fSpinnerLabel.Text = 'f';
            app.fSpinner = uispinner(app.UIFigure);
            app.fSpinner.Limits = [0 1000];
            app.fSpinner.ValueChangedFcn = createCallbackFcn(app, @fSpinnerValueChanged, true);
            app.fSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end