classdef fcn_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AxisMoveButton         matlab.ui.control.StateButton
        RiseRatioSlider        matlab.ui.control.Slider
        RiseRatioSliderLabel   matlab.ui.control.Label
        RiseRatioSpinner       matlab.ui.control.Spinner
        RiseRatioSpinnerLabel  matlab.ui.control.Label
        DutyRatioSpinner       matlab.ui.control.Spinner
        DutyRatioSpinnerLabel  matlab.ui.control.Label
        DutyRatioSlider        matlab.ui.control.Slider
        DutyRatioSliderLabel   matlab.ui.control.Label
        PlayButton             matlab.ui.control.StateButton
        lgxSlider              matlab.ui.control.Slider
        lgxSliderLabel         matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        RunButton              matlab.ui.control.Button
        Label_3                matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        RMSEditField           matlab.ui.control.EditField
        RMSEditFieldLabel      matlab.ui.control.Label
        PPEditField            matlab.ui.control.EditField
        PPEditFieldLabel       matlab.ui.control.Label
        PeakEditField          matlab.ui.control.EditField
        PeakEditFieldLabel     matlab.ui.control.Label
        TEditField             matlab.ui.control.EditField
        Label                  matlab.ui.control.Label
        StdEditField           matlab.ui.control.EditField
        StdLabel               matlab.ui.control.Label
        AvgEditField           matlab.ui.control.EditField
        AvgLabel               matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        NoiseSpinner           matlab.ui.control.Spinner
        Label_2                matlab.ui.control.Label
        biasSpinner            matlab.ui.control.Spinner
        biasSpinnerLabel       matlab.ui.control.Label
        ASpinner               matlab.ui.control.Spinner
        ASpinnerLabel          matlab.ui.control.Label
        fSpinner               matlab.ui.control.Spinner
        fSpinnerLabel          matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        DropDownLabel          matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        PlotButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        f = 50; 
        A = 1;
        x_length = 0.1;
        Fs = 44100;
        mode = 0;
        t;
        x;
        y;
        bias = 0; % Description
        phi = 0;
        noise_std = 0;
        state_run = 0;
        %方波或三角波特有
        DutyRatio = 0.5;
        RiseRatio = 0.5;
    end
    
    methods (Access = private)
        function plot_fcn(app)
            dt = 1.0 / app.Fs;
            app.t = 0:dt:app.x_length;
            app.x =app.t+app.phi;
            switch (app.mode)
                case 0
                    app.y = app.A*sin(2*3.14*app.f*app.x);
                case 1
                    ratio = app.RiseRatio;
                    y1 = app.A * (app.x-floor(app.x * app.f)/app.f) * app.f /ratio .* ((app.x-floor(app.x * app.f)/app.f - ratio/app.f) <0);
                    y2 = app.A * (ceil(app.x * app.f)/app.f-app.x) * app.f / ( 1 - ratio) .* ((app.x-floor(app.x * app.f)/app.f - ratio /app.f) >=0);
                    app.y = y1 + y2;
                    %ratio=0时 y=nan
                    if ratio == 0
                        app.y = y2;
                    else 
                        if ratio ==1
                            app.y = y1;
                        end
                    end
                    %assignin('base','y',app.y)
                case 2
                    app.y = app.A * ((app.x - floor(app.x * app.f)/app.f - (app.DutyRatio )/ app.f)<0);
                otherwise
                    %app.y = randn(1,length(app.x))*max([app.noise_std,0.0001])+app.bias;
                    rng('shuffle')
                    app.y = randn(1,length(app.x))*app.A/3;
            end
%             fprintf(num2str(app.mode))
%             if app.mode == 0
%                 app.y = app.A*sin(2*3.14*app.f*app.x);
%                 fprintf('sin\n');
%             else
%                 if app.mode == 1
%                     app.y = app.A * (app.x-floor(app.x * app.f)/app.f) * app.f;
%                     fprintf('tri\n');
%                 else 
%                     if app.mode ==2
%                         app.y = app.A * ((app.x - floor(app.x * app.f)/app.f-1/(2*app.f))>0);
%                         fprintf('rect\n');
%                     end
%                 end
%             end
            app.y = app.y+app.bias;
            app.y = app.y + randn(1,length(app.x)) * app.noise_std;
            %静态X轴
            if app.AxisMoveButton.Value ==0
            plot(app.UIAxes , app.t , app.y);
            xlim(app.UIAxes , [0,app.x_length]);
            else
            %动态X轴
            plot(app.UIAxes , app.x , app.y);
            xlim(app.UIAxes , [min(app.x),max(app.x)]);
            end
            ylim(app.UIAxes , [min(app.y)-0.1 , max(app.y)+0.1]);
            grid(app.UIAxes , 'on');
        end

        function flush(app)
            app.plot_fcn();

            app.fSpinner.Value = app.f;
            app.ASpinner.Value = app.A;
            app.biasSpinner.Value = app.bias;
            app.NoiseSpinner.Value = app.noise_std;
            app.TEditField.Value = num2str(app.calcT());
            app.AvgEditField.Value = num2str(mean(app.y));
            app.StdEditField.Value = num2str(std(app.y) );
            app.PeakEditField.Value = num2str(max(app.y) - mean(app.y));
            app.PPEditField.Value = num2str( max(app.y) - min(app.y));
            app.RMSEditField.Value = num2str(rms(app.y));

            app.DutyRatioSlider.Value = app.DutyRatio*100;
            app.DutyRatioSpinner.Value = app.DutyRatio;
            app.RiseRatioSlider.Value = app.RiseRatio*100;
            app.RiseRatioSpinner.Value = app.RiseRatio;
        end
        
        function results = calcT(app)
            y1 = app.y-mean(app.y);
            cnt = sum(abs(diff(y1>0)))/2;
            results = app.x_length/cnt;
             assignin('base','workcnt',abs(diff(y1>0)))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
              app.flush();
              
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Value changed function: fSpinner
        function fSpinnerValueChanged(app, event)
            value = app.fSpinner.Value;
            app.f = value;
            app.flush();
        end

        % Value changed function: ASpinner
        function ASpinnerValueChanged(app, event)
            value = app.ASpinner.Value;
            app.A = value;
            app.flush();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.A = 1;
            app.f = 50;
            app.bias = 0;
            app.noise_std = 0;
            app.state_run = 0;
            app.phi = 0;
            app.flush();
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == 2
                app.DutyRatioSlider.Visible = "on";
                app.DutyRatioSliderLabel.Visible = "on";
                app.DutyRatioSpinner.Visible = "on";
            else
                app.DutyRatioSlider.Visible = "off";
                app.DutyRatioSliderLabel.Visible = "off";
                app.DutyRatioSpinner.Visible = "off";
            end
            if value == 1
                app.RiseRatioSlider.Visible = "on";
                app.RiseRatioSliderLabel.Visible = "on";
                app.RiseRatioSpinner.Visible = "on";
            else
                app.RiseRatioSlider.Visible = "off";
                app.RiseRatioSliderLabel.Visible = "off";
                app.RiseRatioSpinner.Visible = "off";
            end
            app.mode = value;
            app.flush();
        end

        % Value changed function: biasSpinner
        function biasSpinnerValueChanged(app, event)
            value = app.biasSpinner.Value;
            app.bias = value;
            app.flush();
        end

        % Value changed function: NoiseSpinner
        function NoiseSpinnerValueChanged(app, event)
            value = app.NoiseSpinner.Value;
            app.noise_std = value;
            app.flush();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.state_run = 1;
            while  app.state_run == 1
                %app.phi = app.phi + 1/app.Fs*100;
                app.phi = app.phi + 0.1*app.x_length/10;
                app.flush();
                pause(0.1);
             end
        end

        % Value changed function: lgxSlider
        function lgxSliderValueChanged(app, event)
            value = app.lgxSlider.Value;
            app.x_length = 10^value ;
            app.flush();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           app.state_run = 0;
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value == 1
                sound(app.y);
            end
            app.PlayButton.Value = 0;
        end

        % Value changed function: DutyRatioSlider
        function DutyRatioSliderValueChanged(app, event)
            value = app.DutyRatioSlider.Value;
            app.DutyRatio = value / 100;
            app.flush();
        end

        % Value changed function: DutyRatioSpinner
        function DutyRatioSpinnerValueChanged(app, event)
            value = app.DutyRatioSpinner.Value;
            app.DutyRatio = value;
            app.flush()
        end

        % Value changed function: RiseRatioSlider
        function RiseRatioSliderValueChanged(app, event)
            value = app.RiseRatioSlider.Value;
            app.RiseRatio = value / 100;
            app.flush();
        end

        % Value changed function: RiseRatioSpinner
        function RiseRatioSpinnerValueChanged(app, event)
            value = app.RiseRatioSpinner.Value;
            app.RiseRatio = value;
            app.flush();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Y=fcn(x)')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '函数发生器';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'正弦波', '锯齿波', '方波', '白噪声'};
            app.DropDown.ItemsData = [0 1 2 3];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 0;
            app.fSpinnerLabel = uilabel(app.Panel);
            app.fSpinnerLabel.Text = 'f';
            app.fSpinner = uispinner(app.Panel);
            app.fSpinner.ValueChangedFcn = createCallbackFcn(app, @fSpinnerValueChanged, true);
            app.fSpinner.Value = 50;
            app.ASpinnerLabel = uilabel(app.Panel);
            app.ASpinnerLabel.Text = 'A';
            app.ASpinner = uispinner(app.Panel);
            app.ASpinner.Limits = [0.01 Inf];
            app.ASpinner.ValueChangedFcn = createCallbackFcn(app, @ASpinnerValueChanged, true);
            app.ASpinner.Value = 1;
            app.biasSpinnerLabel = uilabel(app.Panel);
            app.biasSpinnerLabel.Text = 'bias';
            app.biasSpinner = uispinner(app.Panel);
            app.biasSpinner.ValueChangedFcn = createCallbackFcn(app, @biasSpinnerValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Noise';
            app.NoiseSpinner = uispinner(app.Panel);
            app.NoiseSpinner.Step = 0.01;
            app.NoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @NoiseSpinnerValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '参数分析';
            app.AvgLabel = uilabel(app.Panel_2);
            app.AvgLabel.Text = '均值 Avg';
            app.AvgEditField = uieditfield(app.Panel_2, 'text');
            app.AvgEditField.Editable = 'off';
            app.StdLabel = uilabel(app.Panel_2);
            app.StdLabel.Text = '标准差 Std';
            app.StdEditField = uieditfield(app.Panel_2, 'text');
            app.StdEditField.Editable = 'off';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '周期 T';
            app.TEditField = uieditfield(app.Panel_2, 'text');
            app.TEditField.Editable = 'off';
            app.PeakEditFieldLabel = uilabel(app.Panel_2);
            app.PeakEditFieldLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.Panel_2, 'text');
            app.PeakEditField.Editable = 'off';
            app.PPEditFieldLabel = uilabel(app.Panel_2);
            app.PPEditFieldLabel.Text = 'P-P';
            app.PPEditField = uieditfield(app.Panel_2, 'text');
            app.PPEditField.Editable = 'off';
            app.RMSEditFieldLabel = uilabel(app.Panel_2);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMSEditField = uieditfield(app.Panel_2, 'text');
            app.RMSEditField.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '注：参数分析中过零点分析在加入噪声时会使得所得频率变大。';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.lgxSliderLabel = uilabel(app.UIFigure);
            app.lgxSliderLabel.Text = 'lg x';
            app.lgxSlider = uislider(app.UIFigure);
            app.lgxSlider.Limits = [-2 2];
            app.lgxSlider.MajorTicks = [-2 -1 0 1 2];
            app.lgxSlider.ValueChangedFcn = createCallbackFcn(app, @lgxSliderValueChanged, true);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.DutyRatioSliderLabel = uilabel(app.UIFigure);
            app.DutyRatioSliderLabel.Visible = 'off';
            app.DutyRatioSliderLabel.Text = 'DutyRatio';
            app.DutyRatioSlider = uislider(app.UIFigure);
            app.DutyRatioSlider.ValueChangedFcn = createCallbackFcn(app, @DutyRatioSliderValueChanged, true);
            app.DutyRatioSlider.Visible = 'off';
            app.DutyRatioSlider.Value = 50;
            app.DutyRatioSpinnerLabel = uilabel(app.UIFigure);
            app.DutyRatioSpinnerLabel.Visible = 'off';
            app.DutyRatioSpinnerLabel.Text = 'DutyRatio';
            app.DutyRatioSpinner = uispinner(app.UIFigure);
            app.DutyRatioSpinner.Step = 0.01;
            app.DutyRatioSpinner.Limits = [0 1];
            app.DutyRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @DutyRatioSpinnerValueChanged, true);
            app.DutyRatioSpinner.Visible = 'off';
            app.DutyRatioSpinner.Value = 1;
            app.RiseRatioSpinnerLabel = uilabel(app.UIFigure);
            app.RiseRatioSpinnerLabel.Visible = 'off';
            app.RiseRatioSpinnerLabel.Text = 'RiseRatio';
            app.RiseRatioSpinner = uispinner(app.UIFigure);
            app.RiseRatioSpinner.Step = 0.01;
            app.RiseRatioSpinner.Limits = [0 1];
            app.RiseRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @RiseRatioSpinnerValueChanged, true);
            app.RiseRatioSpinner.Visible = 'off';
            app.RiseRatioSpinner.Value = 1;
            app.RiseRatioSliderLabel = uilabel(app.UIFigure);
            app.RiseRatioSliderLabel.Visible = 'off';
            app.RiseRatioSliderLabel.Text = 'RiseRatio';
            app.RiseRatioSlider = uislider(app.UIFigure);
            app.RiseRatioSlider.ValueChangedFcn = createCallbackFcn(app, @RiseRatioSliderValueChanged, true);
            app.RiseRatioSlider.Visible = 'off';
            app.RiseRatioSlider.Value = 50;
            app.AxisMoveButton = uibutton(app.UIFigure, 'state');
            app.AxisMoveButton.Text = 'AxisMove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fcn_gen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end