classdef media < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SoundTraceButtonGroup         matlab.ui.container.ButtonGroup
        RightButton                   matlab.ui.control.RadioButton
        LeftButton                    matlab.ui.control.RadioButton
        PlayButton                    matlab.ui.control.StateButton
        PlotButton                    matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        LengthSpinner                 matlab.ui.control.Spinner
        sLabel_2                      matlab.ui.control.Label
        WindowLengthSlider            matlab.ui.control.Slider
        WindowLengthSliderLabel       matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        TimeSliderLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        AudioTimeEditField            matlab.ui.control.EditField
        AudioTimeEditFieldLabel       matlab.ui.control.Label
        Audio_SampleFsEditField       matlab.ui.control.EditField
        Audio_SampleFsEditFieldLabel  matlab.ui.control.Label
        Video_FPSEditField            matlab.ui.control.EditField
        Video_FPSEditFieldLabel       matlab.ui.control.Label
        Video_HWCEditField            matlab.ui.control.EditField
        Video_HWCLabel                matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel            matlab.ui.control.Label
        FileButton                    matlab.ui.control.Button
        SourceEditField               matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file;
        path;
        file_index;
        is_mp4;
        video = [];
        audio = [];
        fs = 0;
        window = [];
        window_length = 10;%窗口长度,s
        current_time = 0;
        audio_left;
        audio_right;
        audio_step = 0.1'%最小播放长度;输入为mp4时为1/fps
        sound_track = 0;
        
        state_play = 0;
        state_run = 0;
    end
    
    methods (Access = private)
        
        function flush(app)
            %Compoment
            if strcmp(app.TimeSlider.Tag,'connected')
                app.TimeSlider.Value = app.current_time;
            end
            app.LengthSpinner.Value = app.window_length;
            app.WindowLengthSlider.Value = log10(app.window_length);
            app.UIAxes.XLim = [app.current_time,app.current_time + app.window_length];
            
            l = size(app.audio);
            app.audio_left = max(ceil(app.current_time*app.fs),1);
            app.audio_right = min(floor(app.fs*(app.current_time+app.window_length)),l(1));
            app.window = app.audio( app.audio_left:app.audio_right,app.sound_track);
            
            t = linspace(app.current_time,app.current_time+app.window_length,length(app.window));
            plot(app.UIAxes,t,app.window);
            %ylim(app.UIAxes,[min(min(app.window),-0.1),max(max(app.window),0.1)])
            ylim(app.UIAxes,[-1,1]);
            window_fft = abs(fft(app.window));
            f = linspace(0,app.fs/2,floor(length(window_fft)/2));
            plot(app.UIAxes2,f,window_fft(1 : floor(length(window_fft)/2)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
             [app.file,app.path,index] = uigetfile('*.mp4;*.mp3');
             if index==0
                return
             end

             text = app.FileButton.Text;
             app.FileButton.Text = 'loading';
             if strcmp(app.file(length(app.file)-3:length(app.file)),'.mp4')
                 app.is_mp4 = 1;
                 app.video = VideoReader([app.path,app.file]);
                 app.TimeEditField.Value = sprintf('%.2f s',app.video.Duration);
                 app.Video_FPSEditField.Value = sprintf('%.0f',app.video.FrameRate);
                 
                 app.Video_HWCEditField.Value = sprintf('%d×%d×%d',app.video.Height,app.video.Width,app.video.BitsPerPixel/8);
                 app.audio_step = 2/app.video.FrameRate;
                 %app.TimeEditField.Value = num2str( app.video.Duration);
             else
                 app.is_mp4 = 0;
             end
             %audio
              [app.audio,app.fs] = audioread([app.path,app.file]);
              app.Audio_SampleFsEditField.Value = sprintf('%d',app.fs);
              l = size(app.audio);
              app.AudioTimeEditField.Value = sprintf('%.2f',l(1)/app.fs);
              app.LeftButton.Value =1;
              if l(2) ==2
                  app.RightButton.Visible ='on';
              else
                      app.RightButton.Visible ='off';
              end
              app.sound_track = 1;
              %slider
              app.TimeSlider.Tag = 'connected';
              app.TimeSlider.Limits = [0 , floor(l(1)/app.fs)];
              
              %source
              app.SourceEditField.Value = [app.path,app.file];
              app.FileButton.Text = text;
              app.flush();
        end

        % Value changing function: WindowLengthSlider
        function WindowLengthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.window_length = 10^changingValue;
            app.flush();
        end

        % Value changing function: LengthSpinner
        function LengthSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.window_length = changingValue;
            app.flush();
        end

        % Value changed function: LengthSpinner
        function LengthSpinnerValueChanged(app, event)
            value = app.LengthSpinner.Value;
            app.window_length = value;
            app.flush();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.flush();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.state_run = 1;
            app.flush();
            l = size(app.audio);
            len = l(1);
            use_sound = 0;
            while app.state_run == 1 
                if app.audio_right >= len
                    app.state_run = 0;
                    break;
                end
                step_right = min(floor(app.fs*(app.current_time+app.audio_step)),l(1));
                if app.state_play ==1 && use_sound
                    sound(app.audio(app.audio_left : step_right,app.sound_track),app.fs);
                end
                app.current_time = app.current_time + app.audio_step;
                app.flush();
                pause(app.audio_step);
            end
            app.flush();
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            app.state_play = value;
            app.flush();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.state_run = 0;
            app.flush();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.current_time = 0;
            app.state_play = 1;
            app.state_run = 0;
            app.flush();
        end

        % Selection changed function: SoundTraceButtonGroup
        function SoundTraceButtonGroupSelectionChanged(app, event)
            if app.RightButton.Value == 1
                app.sound_track = 2;
            else
                app.sound_track = 1;
            end
            app.flush();
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            assignin('base',"event",event)
            app.TimeSlider.Tag = 'changing';
            if app.state_run ==0
                app.current_time = changingValue;
                app.flush();
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            app.current_time = value;
            app.TimeSlider.Tag = 'connected';
            app.flush();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fcn')
            xlabel(app.UIAxes, 't /s')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'fcn';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '频谱')
            xlabel(app.UIAxes2, 'f /Hz')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Source';
            app.SourceEditField = uieditfield(app.UIFigure, 'text');
            app.SourceEditField.Editable = 'off';
            app.FileButton = uibutton(app.UIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = 'File';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '文件信息';
            app.TimeEditFieldLabel = uilabel(app.Panel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.Panel, 'text');
            app.TimeEditField.Editable = 'off';
            app.Video_HWCLabel = uilabel(app.Panel);
            app.Video_HWCLabel.Text = 'Video_HWC';
            app.Video_HWCEditField = uieditfield(app.Panel, 'text');
            app.Video_HWCEditField.Editable = 'off';
            app.Video_FPSEditFieldLabel = uilabel(app.Panel);
            app.Video_FPSEditFieldLabel.Text = 'Video_FPS';
            app.Video_FPSEditField = uieditfield(app.Panel, 'text');
            app.Video_FPSEditField.Editable = 'off';
            app.Audio_SampleFsEditFieldLabel = uilabel(app.Panel);
            app.Audio_SampleFsEditFieldLabel.Text = 'Audio_SampleFs';
            app.Audio_SampleFsEditField = uieditfield(app.Panel, 'text');
            app.Audio_SampleFsEditField.Editable = 'off';
            app.AudioTimeEditFieldLabel = uilabel(app.Panel);
            app.AudioTimeEditFieldLabel.Text = 'AudioTime';
            app.AudioTimeEditField = uieditfield(app.Panel, 'text');
            app.AudioTimeEditField.Editable = 'off';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.WindowLengthSliderLabel = uilabel(app.UIFigure);
            app.WindowLengthSliderLabel.Text = 'Window Length';
            app.WindowLengthSlider = uislider(app.UIFigure);
            app.WindowLengthSlider.Limits = [-2 2];
            app.WindowLengthSlider.ValueChangingFcn = createCallbackFcn(app, @WindowLengthSliderValueChanging, true);
            app.WindowLengthSlider.Value = 1;
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = 's';
            app.LengthSpinner = uispinner(app.UIFigure);
            app.LengthSpinner.ValueChangingFcn = createCallbackFcn(app, @LengthSpinnerValueChanging, true);
            app.LengthSpinner.Limits = [0.01 100];
            app.LengthSpinner.ValueChangedFcn = createCallbackFcn(app, @LengthSpinnerValueChanged, true);
            app.LengthSpinner.Value = 10;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.SoundTraceButtonGroup = uibuttongroup(app.UIFigure);
            app.SoundTraceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SoundTraceButtonGroupSelectionChanged, true);
            app.SoundTraceButtonGroup.Title = 'SoundTrace';
            app.LeftButton = uiradiobutton(app.SoundTraceButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.SoundTraceButtonGroup);
            app.RightButton.Text = 'Right';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = media

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end