classdef wave_corr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        RandCheckBox          matlab.ui.control.CheckBox
        RandSpinner           matlab.ui.control.Spinner
        RandnlogSlider        matlab.ui.control.Slider
        RandnlogSliderLabel   matlab.ui.control.Label
        BiasSpinner           matlab.ui.control.Spinner
        FSpinner              matlab.ui.control.Spinner
        ASpinner              matlab.ui.control.Spinner
        BiasSlider            matlab.ui.control.Slider
        BiasSliderLabel       matlab.ui.control.Label
        lgFSlider             matlab.ui.control.Slider
        lgFSliderLabel        matlab.ui.control.Label
        ASlider               matlab.ui.control.Slider
        ASliderLabel          matlab.ui.control.Label
        FcnTypeDropDown       matlab.ui.control.DropDown
        FcnTypeDropDownLabel  matlab.ui.control.Label
        FcnDropDown           matlab.ui.control.DropDown
        FcnDropDownLabel      matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        PauseButton           matlab.ui.control.Button
        RunButton             matlab.ui.control.Button
        Y2CheckBox            matlab.ui.control.CheckBox
        Y1CheckBox            matlab.ui.control.CheckBox
        lgXSlider             matlab.ui.control.Slider
        lgXSliderLabel        matlab.ui.control.Label
        UIAxes5               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        T=10;
        N = 10000;
        t_start = [0,0];
        A =  [1,1];
        F = [1 1];
        bias = [0,0];
        fcn = [5,5];
        state_run = [0,0];
        Randn = [1,1];
        use_rand = [0 0];
    end
    properties (Access = public)
        Y = [];
        x = [];
%         Y =zeros(T*Fs,2);
%         x = [ linspace(t_start(1),t_start(1)+T,length(Y)) ; linspace(t_start(2),t_start(2)+T,length(Y))]';
    end
    methods (Access = private)
        function Y = my_triangle(~,x,f ,A,ratio)
            y1 = A * (x-floor(x *f)/f) *f /ratio .* ((x-floor(x * f)/f - ratio/f) <0);
            y2 = A * (ceil(x * f)/f-x) * f / ( 1 - ratio) .* ((x-floor(x * f)/f - ratio /f) >=0);
            Y= y1 + y2;
             return
        end
        function Y = my_square(~,x,f ,A,ratio)
            Y = A * ((x - floor(x * f)/f - (ratio )/ f)<0);
             return
        end
        function fcn_gen(app,index)
            app.x(:,index) = linspace(app.t_start(index),app.t_start(index)+app.T,app.N);
            %app.fcn(index) = app.FcnTypeDropDown.Value - '0';
            switch app.fcn(index)
                case 1
                    app.Y( : ,index) = app.A(index) * sin(2*pi*app.F(index) *app.x(: , index))+app.bias(index);
                case 2
                    app.Y( : ,index) = app.my_square( app.x(:,index), app.F(index), app.A(index), 0.5)+app.bias(index) ;
                case 3
                    app.Y( : ,index) = app.my_triangle( app.x(:,index), app.F(index), app.A(index), 0.5)+app.bias(index);
                case 4
                    app.Y( : ,index) = app.A(index) * randn(length(app.x),1)+app.bias(index);
                otherwise
                    %app.Y( : ,index) = app.A(index) * sin(2*pi*app.F(index) *app.x(: ,index))+app.bias(index);
                    app.Y( : ,index) = 0 *sin(2*pi*app.F(index) *app.x(: ,index));
            end
            if app.use_rand(index) ==1
                app.Y(:,index) = app.Y(:,index) + app.Randn(index) * randn(length(app.x),1)+app.bias(index);
            end
        end
        function plotAxes(app)
            plot(app.UIAxes,app.x( : , 1) , app.Y( : , 1));
            
            plot(app.UIAxes3, linspace(-app.T,app.T,2*app.N - 1),xcorr( app.Y( : , 1),app.Y( : , 1),'normalized'));
            assignin('base','time',[app.t_start(1),app.t_start(1)+app.T])
            xlim(app.UIAxes,[app.t_start(1),app.t_start(1)+app.T]);
            xlim(app.UIAxes3,[-app.T,app.T]);
            s = size(app.x);
            if s(2) ==2
                plot(app.UIAxes2,app.x( : ,2)' , app.Y( : , 2)');
                plot(app.UIAxes4, linspace(-app.T,app.T,2*app.N - 1),xcorr( app.Y( : , 2),app.Y( : , 2),'normalized'));
                plot(app.UIAxes5, linspace(-app.T,app.T,2*app.N - 1),xcorr( app.Y( : , 1),app.Y( : , 2),'normalized'));
                xlim(app.UIAxes2,[app.t_start(2),app.t_start(2)+app.T]);
                xlim(app.UIAxes4,[-app.T,app.T]);
                xlim(app.UIAxes5,[-app.T,app.T]);
            end
        end
        function flush_ui(app)
            index = app.FcnDropDown.Value -'0';
            app.ASlider.Value = app.A(index);
            app.ASpinner.Value = app.A(index);
            app.lgFSlider.Value = log10(app.F(index));
            app.FSpinner.Value = app.F(index);
            app.BiasSlider.Value = app.bias(index);
            app.BiasSpinner.Value = app.bias(index);
            app.RandnlogSlider.Value = log10(app.Randn(index));
            app.RandSpinner.Value = app.Randn(index);
            app.RandCheckBox.Value = app.use_rand(index);

            app.Y1CheckBox.Value =app.state_run(1);
            app.Y2CheckBox.Value =app.state_run(2);
            app.FcnTypeDropDown.Value = app.fcn(index) + '0';
        end
        function flush(app)
%             index = app.FcnDropDown.Value - '0';
            app.fcn_gen(1);
            app.fcn_gen(2);
            app.plotAxes();
            app.flush_ui();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: lgXSlider
        function lgXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.T = 10^changingValue;
            app.flush();
        end

        % Value changed function: FcnDropDown
        function FcnDropDownValueChanged(app, event)
            value = app.FcnDropDown.Value;
            app.flush_ui();
        end

        % Value changed function: FcnTypeDropDown
        function FcnTypeDropDownValueChanged(app, event)
            value = app.FcnTypeDropDown.Value;
            index = app.FcnDropDown.Value -'0';
            app.fcn(index) = value - '0';
            app.flush();
        end

        % Value changing function: ASlider
        function ASliderValueChanging(app, event)
            changingValue = event.Value;
            index = app.FcnDropDown.Value -'0';
            app.A(index) = changingValue;
            app.flush();
        end

        % Value changed function: ASpinner
        function ASpinnerValueChanged(app, event)
            value = app.ASpinner.Value;
            index = app.FcnDropDown.Value - '0';
            app.A(index) = value;
            app.flush();
        end

        % Value changing function: lgFSlider
        function lgFSliderValueChanging(app, event)
            changingValue = event.Value;
            index = app.FcnDropDown.Value -'0';
            app.F(index) = 10 ^ changingValue;
            app.flush();
        end

        % Value changed function: FSpinner
        function FSpinnerValueChanged(app, event)
            value = app.FSpinner.Value;
            index = app.FcnDropDown.Value -'0';
            app.F(index) = value;
            app.flush();
        end

        % Value changing function: BiasSlider
        function BiasSliderValueChanging(app, event)
            changingValue = event.Value;
            index = app.FcnDropDown.Value -'0';
            app.bias(index) = changingValue;
            app.flush();
        end

        % Value changed function: BiasSpinner
        function BiasSpinnerValueChanged(app, event)
            value = app.BiasSpinner.Value;
            index = app.FcnDropDown.Value -'0';
            app.bias(index) = value;
            app.flush();
        end

        % Value changing function: RandnlogSlider
        function RandnlogSliderValueChanging(app, event)
            changingValue = event.Value;
            index = app.FcnDropDown.Value -'0';
            app.Randn(index) = 10 ^ changingValue;
            app.use_rand(index) = 1;
            app.flush();
        end

        % Value changed function: RandSpinner
        function RandSpinnerValueChanged(app, event)
            value = app.RandSpinner.Value;
            index = app.FcnDropDown.Value -'0';
            app.Randn(index) = value;
            app.use_rand(index) = 1;
            app.flush();
        end

        % Value changed function: RandCheckBox
        function RandCheckBoxValueChanged(app, event)
            value = app.RandCheckBox.Value;
            index = app.FcnDropDown.Value -'0';
            app.use_rand(index) =value;
            app.flush();
        end

        % Value changed function: lgXSlider
        function lgXSliderValueChanged(app, event)
            value = app.lgXSlider.Value;
            
        end

        % Value changed function: Y1CheckBox
        function Y1CheckBoxValueChanged(app, event)
            value = app.Y1CheckBox.Value;
            app.state_run(1) = value;
        end

        % Value changed function: Y2CheckBox
        function Y2CheckBoxValueChanged(app, event)
            value = app.Y2CheckBox.Value;
            app.state_run(2) = value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.flush();
            if isempty(app.x)
                return
            end
            app.state_run =[1,1];
            tic;
            while app.state_run(1)==1 ||app.state_run(2) ==1
                pause(0.1);
                app.flush();
                app.t_start([app.state_run(1)==1,app.state_run(2) ==1]) = app.t_start([app.state_run(1)==1,app.state_run(2) ==1]) +toc;
                tic;
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.state_run =[0,0];
            app.flush();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.t_start =[0,0];
            app.state_run =[0,0];
            app.flush();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Y1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Y2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Y1 corr')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Y2 corr')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'X-Y corr')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.lgXSliderLabel = uilabel(app.UIFigure);
            app.lgXSliderLabel.Text = 'lg X';
            app.lgXSlider = uislider(app.UIFigure);
            app.lgXSlider.Limits = [-2 2];
            app.lgXSlider.ValueChangedFcn = createCallbackFcn(app, @lgXSliderValueChanged, true);
            app.lgXSlider.ValueChangingFcn = createCallbackFcn(app, @lgXSliderValueChanging, true);
            app.lgXSlider.Value = 1;
            app.Y1CheckBox = uicheckbox(app.UIFigure);
            app.Y1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Y1CheckBoxValueChanged, true);
            app.Y1CheckBox.Text = 'Y1';
            app.Y2CheckBox = uicheckbox(app.UIFigure);
            app.Y2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Y2CheckBoxValueChanged, true);
            app.Y2CheckBox.Text = 'Y2';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.FcnDropDownLabel = uilabel(app.Panel);
            app.FcnDropDownLabel.Text = 'Fcn';
            app.FcnDropDown = uidropdown(app.Panel);
            app.FcnDropDown.Items = {'Y1', 'Y2'};
            app.FcnDropDown.ItemsData = {'1', '2'};
            app.FcnDropDown.ValueChangedFcn = createCallbackFcn(app, @FcnDropDownValueChanged, true);
            app.FcnDropDown.Value = '1';
            app.FcnTypeDropDownLabel = uilabel(app.Panel);
            app.FcnTypeDropDownLabel.Text = 'Fcn Type';
            app.FcnTypeDropDown = uidropdown(app.Panel);
            app.FcnTypeDropDown.Items = {'正弦波', '方波', '三角波', '随机', '0'};
            app.FcnTypeDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.FcnTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FcnTypeDropDownValueChanged, true);
            app.FcnTypeDropDown.Value = '5';
            app.ASliderLabel = uilabel(app.Panel);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.Panel);
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanging, true);
            app.lgFSliderLabel = uilabel(app.Panel);
            app.lgFSliderLabel.Text = 'lg F';
            app.lgFSlider = uislider(app.Panel);
            app.lgFSlider.Limits = [-2 2];
            app.lgFSlider.ValueChangingFcn = createCallbackFcn(app, @lgFSliderValueChanging, true);
            app.BiasSliderLabel = uilabel(app.Panel);
            app.BiasSliderLabel.Text = 'Bias';
            app.BiasSlider = uislider(app.Panel);
            app.BiasSlider.Limits = [-100 100];
            app.BiasSlider.ValueChangingFcn = createCallbackFcn(app, @BiasSliderValueChanging, true);
            app.ASpinner = uispinner(app.Panel);
            app.ASpinner.Limits = [0 100];
            app.ASpinner.ValueChangedFcn = createCallbackFcn(app, @ASpinnerValueChanged, true);
            app.ASpinner.Value = 1;
            app.FSpinner = uispinner(app.Panel);
            app.FSpinner.Limits = [0 100];
            app.FSpinner.ValueChangedFcn = createCallbackFcn(app, @FSpinnerValueChanged, true);
            app.FSpinner.Value = 1;
            app.BiasSpinner = uispinner(app.Panel);
            app.BiasSpinner.Limits = [-100 100];
            app.BiasSpinner.ValueChangedFcn = createCallbackFcn(app, @BiasSpinnerValueChanged, true);
            app.BiasSpinner.Value = 1;
            app.RandnlogSliderLabel = uilabel(app.Panel);
            app.RandnlogSliderLabel.Text = 'Randn(log)';
            app.RandnlogSlider = uislider(app.Panel);
            app.RandnlogSlider.Limits = [-2 2];
            app.RandnlogSlider.ValueChangingFcn = createCallbackFcn(app, @RandnlogSliderValueChanging, true);
            app.RandSpinner = uispinner(app.Panel);
            app.RandSpinner.Limits = [0.01 100];
            app.RandSpinner.ValueChangedFcn = createCallbackFcn(app, @RandSpinnerValueChanged, true);
            app.RandSpinner.Value = 1;
            app.RandCheckBox = uicheckbox(app.Panel);
            app.RandCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandCheckBoxValueChanged, true);
            app.RandCheckBox.Text = 'Rand';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wave_corr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end